classdef HARF_drawing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TubularantiresonanthollowcorefibersdrawingsimulationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        DrawingparametersPanel          matlab.ui.container.Panel
        DrawTension_EditField           matlab.ui.control.NumericEditField
        DrawtensiongEditField_2Label    matlab.ui.control.Label
        FeedSpeed_EditField             matlab.ui.control.NumericEditField
        FeedspeedmmminEditField_2Label  matlab.ui.control.Label
        ConstantsPanel                  matlab.ui.container.Panel
        SimulationLength_EditField      matlab.ui.control.NumericEditField
        SimulationlengthmEditFieldLabel  matlab.ui.control.Label
        ExternalTemperature_EditField   matlab.ui.control.NumericEditField
        ExternaltemperatureCEditFieldLabel  matlab.ui.control.Label
        DeltaT_EditField                matlab.ui.control.NumericEditField
        Differenceoftemperaturebetweenthefurnaceandthepreformatz0KLabel  matlab.ui.control.Label
        GravitationalAcceleration_EditField  matlab.ui.control.NumericEditField
        GravitationalaccelerationmsEditFieldLabel  matlab.ui.control.Label
        NewtonHeatTransferCoefficient_EditField  matlab.ui.control.NumericEditField
        NewtonheattransfercoefficientWmKEditFieldLabel  matlab.ui.control.Label
        StefanBoltzmannConstant_EditField  matlab.ui.control.NumericEditField
        StefanBoltzmannconstantWmK4EditFieldLabel  matlab.ui.control.Label
        FurnacePanel                    matlab.ui.container.Panel
        Length_Furnace_EditField        matlab.ui.control.NumericEditField
        LengthmEditFieldLabel           matlab.ui.control.Label
        TemperatureProfile_Furnace_EditField  matlab.ui.control.EditField
        FurnacetemperatureprofileLabel  matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        UIAxes_3                        matlab.ui.control.UIAxes
        JacketTab                       matlab.ui.container.Tab
        GeometricparametersPanel        matlab.ui.container.Panel
        d0D0Label                       matlab.ui.control.Label
        d0_Jacket_EditField             matlab.ui.control.NumericEditField
        d0mmEditField_2Label            matlab.ui.control.Label
        D0_Jacket_EditField             matlab.ui.control.NumericEditField
        D0mmEditField_2Label_2          matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        GrandeurARepresenter_DropDown4  matlab.ui.control.DropDown
        MaterialpropertiesFunctionsofTKPanel  matlab.ui.container.Panel
        Emissivity_Jacket_EditField     matlab.ui.control.EditField
        EmissivityEditFieldLabel        matlab.ui.control.Label
        Viscosity_Jacket_EditField      matlab.ui.control.EditField
        ViscosityPasEditFieldLabel      matlab.ui.control.Label
        SpecificHeat_Jacket_EditField   matlab.ui.control.EditField
        SpecificheatJkgKEditFieldLabel  matlab.ui.control.Label
        SurfaceTension_Jacket_EditField  matlab.ui.control.EditField
        SurfacetensionNmEditFieldLabel  matlab.ui.control.Label
        VolumicMass_Jacket_EditField    matlab.ui.control.EditField
        Volumicmasskgm3EditFieldLabel   matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        CapillariesTab                  matlab.ui.container.Tab
        MaterialpropertiesFunctionsofTCapillaries3Panel  matlab.ui.container.Panel
        Viscosity_Capillaries_EditField_3  matlab.ui.control.EditField
        ViscosityPasEditField_2Label_3  matlab.ui.control.Label
        SurfaceTension_Capillaries_EditField_3  matlab.ui.control.EditField
        SurfacetensionNmEditField_2Label_3  matlab.ui.control.Label
        MaterialpropertiesFunctionsofTCapillaries2Panel  matlab.ui.container.Panel
        Viscosity_Capillaries_EditField_2  matlab.ui.control.EditField
        ViscosityPasEditField_2Label_2  matlab.ui.control.Label
        SurfaceTension_Capillaries_EditField_2  matlab.ui.control.EditField
        SurfacetensionNmEditField_2Label_2  matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        GrandeurARepresenter_DropDown5  matlab.ui.control.DropDown
        GeometricparametersPanel_2      matlab.ui.container.Panel
        Dc03dc02Label                   matlab.ui.control.Label
        Dc02dc01Label                   matlab.ui.control.Label
        Rc01RcontactLabel               matlab.ui.control.Label
        dc0_Capillaries_EditField_3     matlab.ui.control.NumericEditField
        dc01mmLabel_3                   matlab.ui.control.Label
        Dc0_Capillaries_EditField_3     matlab.ui.control.NumericEditField
        Dc01mmLabel_3                   matlab.ui.control.Label
        dc03Dc03Label                   matlab.ui.control.Label
        dc0_Capillaries_EditField_2     matlab.ui.control.NumericEditField
        dc01mmLabel_2                   matlab.ui.control.Label
        Dc0_Capillaries_EditField_2     matlab.ui.control.NumericEditField
        Dc01mmLabel_2                   matlab.ui.control.Label
        dc02Dc02Label                   matlab.ui.control.Label
        dc01Dc01Label                   matlab.ui.control.Label
        dc0_Capillaries_EditField       matlab.ui.control.NumericEditField
        dc01mmLabel                     matlab.ui.control.Label
        Dc0_Capillaries_EditField       matlab.ui.control.NumericEditField
        Dc01mmLabel                     matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        MicrostruturedcladdinggeometryPanel  matlab.ui.container.Panel
        NestedCapillaries_Spinner       matlab.ui.control.Spinner
        NestedCapillariesSpinnerLabel   matlab.ui.control.Label
        NumberOfCapillaries_Spinner     matlab.ui.control.Spinner
        NumberofcapillariesSpinnerLabel  matlab.ui.control.Label
        MaterialpropertiesFunctionsofTCapillaries1Panel  matlab.ui.container.Panel
        Viscosity_Capillaries_EditField  matlab.ui.control.EditField
        ViscosityPasEditField_2Label    matlab.ui.control.Label
        SurfaceTension_Capillaries_EditField  matlab.ui.control.EditField
        SurfacetensionNmEditField_2Label  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        DirectcomputationTab            matlab.ui.container.Tab
        OutputPanel_3                   matlab.ui.container.Panel
        maxDc3dc2Label                  matlab.ui.control.Label
        maxDc2dc1Label                  matlab.ui.control.Label
        maxRc1RcontactLabel             matlab.ui.control.Label
        OutputPanel_2                   matlab.ui.container.Panel
        tf3Label                        matlab.ui.control.Label
        dcf3Label                       matlab.ui.control.Label
        Dcf3Label                       matlab.ui.control.Label
        tf2Label                        matlab.ui.control.Label
        dcf2Label                       matlab.ui.control.Label
        Dcf2Label                       matlab.ui.control.Label
        DcoreLabel                      matlab.ui.control.Label
        VfLabel                         matlab.ui.control.Label
        dcf3Dcf3Label_2                 matlab.ui.control.Label
        dcf2Dcf2Label_2                 matlab.ui.control.Label
        Rcf1RcontactLabel               matlab.ui.control.Label
        SaveresultsButton               matlab.ui.control.Button
        dcf1Dcf1Label_2                 matlab.ui.control.Label
        tf1Label                        matlab.ui.control.Label
        dcf1Label                       matlab.ui.control.Label
        Dcf1Label                       matlab.ui.control.Label
        dfLabel                         matlab.ui.control.Label
        DfLabel                         matlab.ui.control.Label
        InputPanel_2                    matlab.ui.container.Panel
        DeltaP3_EditField               matlab.ui.control.NumericEditField
        PcapmbarLabel_3                 matlab.ui.control.Label
        DeltaP2_EditField               matlab.ui.control.NumericEditField
        PcapmbarLabel_2                 matlab.ui.control.Label
        DeltaP1_EditField               matlab.ui.control.NumericEditField
        PcapmbarLabel                   matlab.ui.control.Label
        Tmax_EditField                  matlab.ui.control.NumericEditField
        TmaxCEditFieldLabel             matlab.ui.control.Label
        Pcore_EditField                 matlab.ui.control.NumericEditField
        PcoremBarEditFieldLabel         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        zmSlider                        matlab.ui.control.Slider
        zmSliderLabel                   matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        GrandeurARepresenter_DropDown1  matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        OptimizationTab                 matlab.ui.container.Tab
        Button_2                        matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        ApproximatevaluesPanel          matlab.ui.container.Panel
        OptimDeltaPmaxEditField_3       matlab.ui.control.NumericEditField
        maxLabel_3                      matlab.ui.control.Label
        OptimDeltaPminEditField_3       matlab.ui.control.NumericEditField
        minEditFieldLabel_5             matlab.ui.control.Label
        DeltaPmbarLabel_4               matlab.ui.control.Label
        OptimDeltaPmaxEditField_2       matlab.ui.control.NumericEditField
        maxLabel_2                      matlab.ui.control.Label
        OptimDeltaPminEditField_2       matlab.ui.control.NumericEditField
        minEditFieldLabel_4             matlab.ui.control.Label
        DeltaPmbarLabel_3               matlab.ui.control.Label
        DeltaPmbarLabel_2               matlab.ui.control.Label
        OptimDeltaPmaxEditField         matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        OptimDeltaPminEditField         matlab.ui.control.NumericEditField
        minEditFieldLabel_3             matlab.ui.control.Label
        OptimPcoremaxEditField          matlab.ui.control.NumericEditField
        maxEditFieldLabel_2             matlab.ui.control.Label
        OptimPcoreminEditField          matlab.ui.control.NumericEditField
        minEditFieldLabel_2             matlab.ui.control.Label
        PcorembarLabel                  matlab.ui.control.Label
        OptimTmaxmaxEditField           matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        OptimTmaxminEditField           matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        TmaxCLabel                      matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        OutputPanel                     matlab.ui.container.Panel
        OptimTmaxLabel                  matlab.ui.control.Label
        OptimPcoreLabel                 matlab.ui.control.Label
        OptimdpLabel_3                  matlab.ui.control.Label
        OptimdpLabel_2                  matlab.ui.control.Label
        maxDc3dc2Label_2                matlab.ui.control.Label
        maxDc2dc1Label_2                matlab.ui.control.Label
        SaveresultsButton_2             matlab.ui.control.Button
        OptimdpLabel                    matlab.ui.control.Label
        OptimmaxLabel                   matlab.ui.control.Label
        GrandeurAReprrsenter_DropDown2  matlab.ui.control.DropDown
        zmSlider_2                      matlab.ui.control.Slider
        zmSlider_2Label                 matlab.ui.control.Label
        ObjectivePanel                  matlab.ui.container.Panel
        Dcf3dcf2Label                   matlab.ui.control.Label
        Dcf2dcf1Label                   matlab.ui.control.Label
        Dcf3Label_2                     matlab.ui.control.Label
        Dcf2Label_2                     matlab.ui.control.Label
        OptimRcf1Rcontact               matlab.ui.control.Label
        OptimVfLabel                    matlab.ui.control.Label
        dcf3Dcf3Label                   matlab.ui.control.Label
        dcf2Dcf2Label                   matlab.ui.control.Label
        dcf1Dcf1Label                   matlab.ui.control.Label
        OptimdfLabel                    matlab.ui.control.Label
        OptimDfLabel                    matlab.ui.control.Label
        OptimDcoreEditField             matlab.ui.control.NumericEditField
        DcoremEditFieldLabel            matlab.ui.control.Label
        OptimDcfEditField               matlab.ui.control.NumericEditField
        DcfmLabel                       matlab.ui.control.Label
        OptimtfEditField                matlab.ui.control.NumericEditField
        tfmLabel                        matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        param % Description
        result % Description
        calcul_fait
        optim
        optim_fait
        paramini
        graph1
        graph2
    end
    
    methods (Access = private)
        
        %Active ou désactive les trucs pour le capillaire #2
        function set_cap2(app,b)
            app.MaterialpropertiesFunctionsofTCapillaries2Panel.Enable=b;
            app.SurfaceTension_Capillaries_EditField_2.Enable=b;
            app.Viscosity_Capillaries_EditField_2.Enable=b;
            app.Dc0_Capillaries_EditField_2.Enable=b;
            app.dc0_Capillaries_EditField_2.Enable=b;
            app.dc02Dc02Label.Enable=b;
            app.DeltaP2_EditField.Enable=b;
            app.DeltaPmbarLabel_3.Enable=b;
            app.OptimDeltaPminEditField_2.Enable=b;
            app.OptimDeltaPmaxEditField_2.Enable=b;
            app.dcf2Dcf2Label.Enable=b;
            app.Dc01mmLabel_2.Enable=b;
            app.dc01mmLabel_2.Enable=b;
            app.SurfacetensionNmEditField_2Label_2.Enable=b;
            app.ViscosityPasEditField_2Label_2.Enable=b;
            app.minEditFieldLabel_4.Enable=b;
            app.maxLabel_2.Enable=b;
            app.PcapmbarLabel_2.Enable=b;
            app.maxDc2dc1Label.Enable=b;
            app.Dc02dc01Label.Enable=b;
            app.Dcf2Label.Enable=b;
            app.dcf2Label.Enable=b;
            app.tf2Label.Enable=b;
            app.dcf2Dcf2Label_2.Enable=b;
            app.Dcf2Label_2.Enable=b;
            app.Dcf2dcf1Label.Enable=b;
            app.maxDc2dc1Label_2.Enable=b;
            app.OptimdpLabel_2.Enable=b;
        end

        %Active ou désactive les trucs pour le capillaire #3
        function set_cap3(app,b)
            app.MaterialpropertiesFunctionsofTCapillaries3Panel.Enable=b;
            app.SurfaceTension_Capillaries_EditField_3.Enable=b;
            app.Viscosity_Capillaries_EditField_3.Enable=b;
            app.Dc0_Capillaries_EditField_3.Enable=b;
            app.dc0_Capillaries_EditField_3.Enable=b;
            app.dc03Dc03Label.Enable=b;
            app.DeltaP3_EditField.Enable=b;
            app.DeltaPmbarLabel_4.Enable=b;
            app.OptimDeltaPminEditField_3.Enable=b;
            app.OptimDeltaPmaxEditField_3.Enable=b;
            app.dcf3Dcf3Label.Enable=b;
            app.Dc01mmLabel_3.Enable=b;
            app.dc01mmLabel_3.Enable=b;
            app.SurfacetensionNmEditField_2Label_3.Enable=b;
            app.ViscosityPasEditField_2Label_3.Enable=b;
            app.minEditFieldLabel_5.Enable=b;
            app.maxLabel_3.Enable=b;
            app.PcapmbarLabel_3.Enable=b;
            app.maxDc3dc2Label.Enable=b;
            app.Dc03dc02Label.Enable=b;
            app.Dcf3Label.Enable=b;
            app.dcf3Label.Enable=b;
            app.tf3Label.Enable=b;
            app.dcf3Dcf3Label_2.Enable=b;
            app.Dcf3Label_2.Enable=b;
            app.Dcf3dcf2Label.Enable=b;
            app.maxDc3dc2Label_2.Enable=b;
            app.OptimdpLabel_3.Enable=b;
        end

        %Met à jour d0/D0 avec les avertissements associés
        function compute_d0D0(app)
            d0 = app.d0_Jacket_EditField.Value;
            D0 = app.D0_Jacket_EditField.Value;
            app.d0D0Label.Text=strcat('d0/D0 = ',num2str(d0/D0));
            color=[0,0,0];
            if (d0>=D0)
                color=[1,0,0];
            end
            app.d0D0Label.FontColor=color;
        end
        
        %Met à jour l'onglet capillaries pour le cap 1
        function compute_dc01Dc01(app)
            dc0 = app.dc0_Capillaries_EditField.Value;
            Dc0 = app.Dc0_Capillaries_EditField.Value;
            app.dc01Dc01Label.Text=strcat('dc01/Dc01 = ',num2str(dc0/Dc0));
            Dcontact=app.d0_Jacket_EditField.Value*sin(pi/app.NumberOfCapillaries_Spinner.Value)/(1+sin(pi/app.NumberOfCapillaries_Spinner.Value));
            app.Rc01RcontactLabel.Text=strcat('Rc01/Rcontact = ',num2str(Dc0/Dcontact));
            color2=[0,0,0];
            color=[0,0,0];
            if (dc0>=Dc0)
                color=[1,0,0];
            end
            if (Dc0>=Dcontact)
                color2=[1,0,0];
            end
            app.dc01Dc01Label.FontColor=color;
            app.Rc01RcontactLabel.FontColor=color2;
        end

        %Met à jour l'onglet capillaries pour le cap 2
        function compute_dc02Dc02(app)
            dc0 = app.dc0_Capillaries_EditField_2.Value;
            Dc0 = app.Dc0_Capillaries_EditField_2.Value;
            app.dc02Dc02Label.Text=strcat('dc02/Dc02 = ',num2str(dc0/Dc0));
            app.Dc02dc01Label.Text=strcat('Dc02/dc01 = ',num2str(Dc0/app.dc0_Capillaries_EditField.Value));
            color=[0,0,0];
            color2=[0,0,0];
            if (dc0>=Dc0)
                color=[1,0,0];
            end
            if (Dc0>=app.dc0_Capillaries_EditField.Value)
                color2=[1,0,0];
            end
            app.dc02Dc02Label.FontColor=color;
            app.Dc02dc01Label.FontColor=color2;
        end

        %Met à jour l'onglet capillaries pour le cap 3
        function compute_dc03Dc03(app)
            dc0 = app.dc0_Capillaries_EditField_3.Value;
            Dc0 = app.Dc0_Capillaries_EditField_3.Value;
            app.dc03Dc03Label.Text=strcat('dc03/Dc03 = ',num2str(dc0/Dc0));
            app.Dc03dc02Label.Text=strcat('Dc03/dc02 = ',num2str(Dc0/app.dc0_Capillaries_EditField_2.Value));
            color=[0,0,0];
            color2=[0,0,0];
            if (dc0>=Dc0)
                color=[1,0,0];
            end
            if (Dc0>=app.dc0_Capillaries_EditField_2.Value)
                color2=[1,0,0];
            end
            app.dc03Dc03Label.FontColor=color;
            app.Dc03dc02Label.FontColor=color2;
        end

        %Met à jour les valeurs dans l'onglet optimization
        function update_optim_objectifs(app)
            Vi = app.FeedSpeed_EditField.Value;
            D0=app.D0_Jacket_EditField.Value;
            d0=app.d0_Jacket_EditField.Value;
            Dc0=app.Dc0_Capillaries_EditField.Value;
            dc0=app.dc0_Capillaries_EditField.Value;
            tf=app.OptimtfEditField.Value;
            Dcf=app.OptimDcfEditField.Value;
            Dcore=app.OptimDcoreEditField.Value;
            dcf=Dcf-2*tf;
            df=Dcore+2*Dcf;
            Vf=(Dc0^2-dc0^2)/(Dcf^2-dcf^2)*Vi;
            Df=sqrt((D0^2-d0^2)*Vi/Vf+df^2);
            Dcontact=df*sin(pi/app.NumberOfCapillaries_Spinner.Value)/(1+sin(pi/app.NumberOfCapillaries_Spinner.Value));
            if(~isnan(dcf))
                app.dcf1Dcf1Label.Text=strcat('dcf1/Dcf1 = ',num2str(dcf/Dcf));
                if (dcf<=0)
                    app.dcf1Dcf1Label.FontColor=[1,0,0];
                else
                    app.dcf1Dcf1Label.FontColor=[0,0,0];
                end
                app.OptimRcf1Rcontact.Text=strcat('Rcf1/Rcontact = ',num2str(Dcf/Dcontact));
                if (Dcf>=Dcontact)
                    app.OptimRcf1Rcontact.FontColor=[1,0,0];
                else
                    app.OptimRcf1Rcontact.FontColor=[0,0,0];
                end
            end
            if(~isnan(df))
                app.OptimdfLabel.Text=strcat('df/Df = ',num2str(df/Df));
            end
            if(~isnan(Vf))
                app.OptimVfLabel.Text=strcat('Vf = ',num2str(Vf*1000),' m/min');
            end
            if(~isnan(Df))
                app.OptimDfLabel.Text=strcat('Df = ',num2str(Df),' µm');
            end
            Dc02=app.Dc0_Capillaries_EditField_2.Value;
            dc02=app.dc0_Capillaries_EditField_2.Value;
            t02=(Dc02-dc02)/2;
            Dcf2=Vi/Vf*(Dc02*t02-t02^2)/tf+tf;
            dcf2=Dcf2-2*tf;
            app.Dcf2Label_2.Text=strcat('Dcf2 = ',num2str(Dcf2),' µm');
            app.Dcf2dcf1Label.Text=strcat('Dcf2/dcf1 = ',num2str(Dcf2/dcf));
            if (Dcf2>dcf)
                app.Dcf2Label_2.FontColor=[1,0,0];
            else
                app.Dcf2Label_2.FontColor=[0,0,0];
            end
            app.dcf2Dcf2Label.Text=strcat('dcf2/Dcf2 = ',num2str(dcf2/Dcf2));
            if (dcf2<=0)
                app.dcf2Dcf2Label.FontColor=[1,0,0];
            else
                app.dcf2Dcf2Label.FontColor=[0,0,0];
            end

            Dc03=app.Dc0_Capillaries_EditField_3.Value;
            dc03=app.dc0_Capillaries_EditField_3.Value;
            t03=(Dc03-dc03)/2;
            Dcf3=Vi/Vf*(Dc03*t03-t03^2)/tf+tf;
            dcf3=Dcf3-2*tf;
            app.Dcf3Label_2.Text=strcat('Dcf3 = ',num2str(Dcf3),' µm');
            app.Dcf3dcf2Label.Text=strcat('Dcf3/dcf2 = ',num2str(Dcf3/dcf2));
            if (Dcf3>dcf2)
                app.Dcf3Label_2.FontColor=[1,0,0];
            else
                app.Dcf3Label_2.FontColor=[0,0,0];
            end
            app.dcf3Dcf3Label.Text=strcat('dcf3/Dcf3 = ',num2str(dcf3/Dcf3));
            if (dcf3<=0)
                app.dcf3Dcf3Label.FontColor=[1,0,0];
            else
                app.dcf3Dcf3Label.FontColor=[0,0,0];
            end
        end

        %Tracé des fonctions de z dans l'onglet direct computation
        function trace(app)
            value = app.GrandeurARepresenter_DropDown1.Value;
            if (app.calcul_fait)
               if (strcmp(value,'V'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.w*60);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'V (m/min)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'D'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.R*2*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'D (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'d'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.r*2*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'d (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Dc'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   if (app.result.nb_nested==1)
                        plot(app.UIAxes,app.result.z,app.result.Rc*2*1E6,'r-');
                        legend(app.UIAxes,'Cap #1');
                   elseif app.result.nb_nested==2
                        plot(app.UIAxes,app.result.z,app.result.Rc*2*1E6,'r-',app.result.z,app.result.Rc2*2*1E6,'g-');
                        legend(app.UIAxes,["Cap #1","Cap #2"]);
                   else
                        plot(app.UIAxes,app.result.z,app.result.Rc*2*1E6,'r-',app.result.z,app.result.Rc2*2*1E6,'g-',app.result.z,app.result.Rc3*2*1E6,'b-');
                        legend(app.UIAxes,["Cap #1","Cap #2","Cap #3"]);
                   end
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Dc (µm)');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'dc'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   if (app.result.nb_nested==1)
                        plot(app.UIAxes,app.result.z,app.result.rc*2*1E6,'r-');
                        legend(app.UIAxes,'Cap #1');
                   elseif app.result.nb_nested==2
                        plot(app.UIAxes,app.result.z,app.result.rc*2*1E6,'r-',app.result.z,app.result.rc2*2*1E6,'g-');
                        legend(app.UIAxes,["Cap #1","Cap #2"]);
                   else
                        plot(app.UIAxes,app.result.z,app.result.rc*2*1E6,'r-',app.result.z,app.result.rc2*2*1E6,'g-',app.result.z,app.result.rc3*2*1E6,'b-');
                        legend(app.UIAxes,["Cap #1","Cap #2","Cap #3"]);
                   end
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'dc (µm)');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'t'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   if (app.result.nb_nested==1)
                        plot(app.UIAxes,app.result.z,app.result.Rc*1E6-app.result.rc*1E6,'r-');
                        legend(app.UIAxes,'Cap #1');
                   elseif app.result.nb_nested==2
                        plot(app.UIAxes,app.result.z,app.result.Rc*1E6-app.result.rc*1E6,'r-',app.result.z,app.result.Rc2*1E6-app.result.rc2*1E6,'g-');
                        legend(app.UIAxes,["Cap #1","Cap #2"]);
                   else
                        plot(app.UIAxes,app.result.z,app.result.Rc*1E6-app.result.rc*1E6,'r-',app.result.z,app.result.Rc2*1E6-app.result.rc2*1E6,'g-',app.result.z,app.result.Rc3*1E6-app.result.rc3*1E6,'b-');
                        legend(app.UIAxes,["Cap #1","Cap #2","Cap #3"]);
                   end
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'t (µm)');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Dcore'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.r*2E6-app.result.Rc*4E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Dcore (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'dc/Dc'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   if (app.result.nb_nested==1)
                        plot(app.UIAxes,app.result.z,app.result.rc./app.result.Rc,'r-');
                        legend(app.UIAxes,'Cap #1');
                   elseif app.result.nb_nested==2
                        plot(app.UIAxes,app.result.z,app.result.rc./app.result.Rc,'r-',app.result.z,app.result.rc2./app.result.Rc2,'g-');
                        legend(app.UIAxes,["Cap #1","Cap #2"]);
                   else
                        plot(app.UIAxes,app.result.z,app.result.rc./app.result.Rc,'r-',app.result.z,app.result.rc2./app.result.Rc2,'g-',app.result.z,app.result.rc3./app.result.Rc3,'b-');
                        legend(app.UIAxes,["Cap #1","Cap #2","Cap #3"]);
                   end

                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'dc/Dc');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Draw tension'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.tau/9.81*1000);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Tension (g)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Temperature'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.T-273,app.result.z,app.param.Tmax*arrayfun(app.param.profil,app.result.z).*(app.result.z<=app.param.Lfour)+app.param.Text*(app.result.z>app.param.Lfour));
                   app.UIAxes.XLim = [0 app.param.L];
                   legend(app.UIAxes,'Preform','Furnace');
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'T (°C)');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Rc1/Rcontact'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.Rc./app.result.rcontact);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Rc1/Rcontact');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Schematic'))
                   app.zmSliderLabel.Visible = 'on';
                   app.zmSlider.Limits = [0 app.result.z(end)];
                   app.zmSlider.MajorTicks=linspace(0,app.result.z(end),11);
                   app.zmSlider.Visible = 'on';
                   z=app.zmSlider.Value;
                   cla(app.UIAxes);
                   [A,pos]=min(abs(z-app.result.z));
                   legend(app.UIAxes,'hide');
                   xlabel(app.UIAxes,'x (µm)');
                   ylabel(app.UIAxes,'y (µm)');
                   app.graph1.r0=rectangle(app.UIAxes,'Position',[-app.result.R(pos)*1E6,-app.result.R(pos)*1E6,2*app.result.R(pos)*1E6,2*app.result.R(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                   app.graph1.r0bis=rectangle(app.UIAxes,'Position',[-app.result.r(pos)*1E6,-app.result.r(pos)*1E6,2*app.result.r(pos)*1E6,2*app.result.r(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                   for j=1:app.param.n
                       if (isreal(app.result.Rc(pos))) && (~isnan(app.result.Rc(pos))) && (isreal(app.result.rc(pos))) && (~isnan(app.result.rc(pos)))
                          xcenter=(app.result.r(pos)-app.result.Rc(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                          ycenter=(app.result.r(pos)-app.result.Rc(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                          app.graph1.r(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.Rc(pos)*1E6,ycenter-app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                          app.graph1.rbis(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.rc(pos)*1E6,ycenter-app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                          if (app.result.nb_nested>1)
                                if (isreal(app.result.Rc2(pos))) && (~isnan(app.result.Rc2(pos))) && (isreal(app.result.rc2(pos))) && (~isnan(app.result.rc2(pos)))
                                  xcenter=(app.result.r(pos)-app.result.Rc2(pos)-app.result.Rc(pos)+app.result.rc(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                                  ycenter=(app.result.r(pos)-app.result.Rc2(pos)-app.result.Rc(pos)+app.result.rc(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                                  app.graph1.r2(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.Rc2(pos)*1E6,ycenter-app.result.Rc2(pos)*1E6,2*app.result.Rc2(pos)*1E6,2*app.result.Rc2(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                                  app.graph1.rbis2(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.rc2(pos)*1E6,ycenter-app.result.rc2(pos)*1E6,2*app.result.rc2(pos)*1E6,2*app.result.rc2(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                                  if (app.result.nb_nested>2)
                                       if (isreal(app.result.Rc3(pos))) && (~isnan(app.result.Rc3(pos))) && (isreal(app.result.rc3(pos))) && (~isnan(app.result.rc3(pos)))
                                          xcenter=(app.result.r(pos)-app.result.Rc3(pos)-app.result.Rc(pos)+app.result.rc(pos)-app.result.Rc2(pos)+app.result.rc2(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                                          ycenter=(app.result.r(pos)-app.result.Rc3(pos)-app.result.Rc(pos)+app.result.rc(pos)-app.result.Rc2(pos)+app.result.rc2(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                                          app.graph1.r3(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.Rc3(pos)*1E6,ycenter-app.result.Rc3(pos)*1E6,2*app.result.Rc3(pos)*1E6,2*app.result.Rc3(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                                          app.graph1.rbis3(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.rc3(pos)*1E6,ycenter-app.result.rc3(pos)*1E6,2*app.result.rc3(pos)*1E6,2*app.result.rc3(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                                       end
                                  end
                               end
                           end
                       end
                   end

                   app.UIAxes.XLim = app.UIAxes.YLim;
                  daspect(app.UIAxes,[1,1,1]);
               end
            else
                if (strcmp(value,'Temperature'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   Lfour=app.Length_Furnace_EditField.Value;
                   L=app.SimulationLength_EditField.Value;
                   Tmax=app.Tmax_EditField.Value;
                   app.UIAxes.XLim = [0 L];
                   if ~isempty(app.paramini.furnace_profile)
                          z=linspace(0,L,1E5);
                          plot(app.UIAxes,z,Tmax*arrayfun(app.paramini.furnace_profile,z).*(z<=Lfour)+app.ExternalTemperature_EditField.Value*(z>Lfour));
                   else
                          z=linspace(Lfour,L,1E5);
                          plot(app.UIAxes,z,app.ExternalTemperature_EditField.Value*(z>=Lfour));
                   end
                  legend(app.UIAxes,'Temperature profile');
                  xlabel(app.UIAxes,'z (m)');
                  ylabel(app.UIAxes,'T (°C)');
                  daspect(app.UIAxes,'auto');
                end
           end
        end

        %Tracé des fonctions de z dans l'onglet optimization
        function trace_optim(app)
            value = app.GrandeurAReprrsenter_DropDown2.Value;
            if (app.optim_fait)
               if (strcmp(value,'V'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.w*60);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'V (m/min)');
                   legend(app.UIAxes_2,strcat('Vf = ',num2str(app.optim.result.w(end)*60),' m/min'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'D'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.R*2*1E6);
                   legend(app.UIAxes_2,strcat('Df = ',num2str(app.optim.result.R(end)*2*1E6),' µm'));
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'D (µm)');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'d'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.r*2*1E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'d (µm)');
                   legend(app.UIAxes_2,strcat('df = ',num2str(app.optim.result.r(end)*2*1E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Dc'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   if (app.optim.result.nb_nested==1)
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*2*1E6,'r-');
                        legend(app.UIAxes_2,strcat('Dcf1 = ',num2str(app.optim.result.Rc(end)*2*1E6),' µm'));
                   elseif app.optim.result.nb_nested==2
                       plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*2*1E6,'r-',app.optim.result.z,app.optim.result.Rc2*2*1E6,'g-');
                       legend(app.UIAxes_2,[strcat("Dcf1 = ",num2str(app.optim.result.Rc(end)*2*1E6)," µm"),strcat("Dcf2 = ",num2str(app.optim.result.Rc2(end)*2*1E6)," µm")]);
                   else
                       plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*2*1E6,'r-',app.optim.result.z,app.optim.result.Rc2*2*1E6,'g-',app.optim.result.z,app.optim.result.Rc3*2*1E6,'b-');
                       legend(app.UIAxes_2,[strcat("Dcf1 = ",num2str(app.optim.result.Rc(end)*2*1E6)," µm"),strcat("Dcf2 = ",num2str(app.optim.result.Rc2(end)*2*1E6)," µm"),strcat("Dcf3 = ",num2str(app.optim.result.Rc3(end)*2*1E6)," µm")]);
                   end
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Dc (µm)');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'dc'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   if (app.optim.result.nb_nested==1)
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc*2*1E6,'r-');
                        legend(app.UIAxes_2,strcat('dcf1 = ',num2str(app.optim.result.rc(end)*2*1E6),' µm'));
                   elseif app.optim.result.nb_nested==2
                       plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc*2*1E6,'r-',app.optim.result.z,app.optim.result.rc2*2*1E6,'g-');
                       legend(app.UIAxes_2,[strcat("dcf1 = ",num2str(app.optim.result.rc(end)*2*1E6)," µm"),strcat("dcf2 = ",num2str(app.optim.result.rc2(end)*2*1E6)," µm")]);
                   else
                       plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc*2*1E6,'r-',app.optim.result.z,app.optim.result.rc2*2*1E6,'g-',app.optim.result.z,app.optim.result.rc3*2*1E6,'b-');
                       legend(app.UIAxes_2,[strcat("dcf1 = ",num2str(app.optim.result.rc(end)*2*1E6)," µm"),strcat("dcf2 = ",num2str(app.optim.result.rc2(end)*2*1E6)," µm"),strcat("dcf3 = ",num2str(app.optim.result.rc3(end)*2*1E6)," µm")]);
                   end
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'dc (µm)');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'t'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   if (app.optim.result.nb_nested==1)
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*1E6-app.optim.result.rc*1E6,'r-');
                        legend(app.UIAxes_2,strcat('tf = ',num2str(app.optim.result.Rc(end)*1E6-app.optim.result.rc(end)*1E6),' µm'));
                   elseif app.optim.result.nb_nested==2
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*1E6-app.optim.result.rc*1E6,'r-',app.optim.result.z,app.optim.result.Rc2*1E6-app.optim.result.rc2*1E6,'g-');
                        legend(app.UIAxes_2,[strcat("tf = ",num2str(app.optim.result.Rc(end)*1E6-app.optim.result.rc(end)*1E6)," µm"),strcat("tf = ",num2str(app.optim.result.Rc2(end)*1E6-app.optim.result.rc2(end)*1E6)," µm")]);
                   else
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*1E6-app.optim.result.rc*1E6,'r-',app.optim.result.z,app.optim.result.Rc2*1E6-app.optim.result.rc2*1E6,'g-',app.optim.result.z,app.optim.result.Rc3*1E6-app.optim.result.rc3*1E6,'b-');
                        legend(app.UIAxes_2,[strcat("tf = ",num2str(app.optim.result.Rc(end)*1E6-app.optim.result.rc(end)*1E6)," µm"),strcat("tf = ",num2str(app.optim.result.Rc2(end)*1E6-app.optim.result.rc2(end)*1E6)," µm"),strcat("tf = ",num2str(app.optim.result.Rc3(end)*1E6-app.optim.result.rc3(end)*1E6)," µm")]);
                   end

                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'t (µm)');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Dcore'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.r*2E6-app.optim.result.Rc*4E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Dcore (µm)');
                   legend(app.UIAxes_2,strcat('Dcore_f = ',num2str(app.optim.result.r(end)*2E6-app.optim.result.Rc(end)*4E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'dc/Dc'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   if (app.optim.result.nb_nested==1)
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc./app.optim.result.Rc,'r-');
                        legend(app.UIAxes_2,strcat('dcf/Dcf = ',num2str(app.optim.result.rc(end)/app.optim.result.Rc(end))));
                   elseif app.optim.result.nb_nested==2
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc./app.optim.result.Rc,'r-',app.optim.result.z,app.optim.result.rc2./app.optim.result.Rc2,'g-');
                        legend(app.UIAxes_2,[strcat("dcf/Dcf = ",num2str(app.optim.result.rc(end)/app.optim.result.Rc(end))),strcat("dcf/Dcf = ",num2str(app.optim.result.rc2(end)/app.optim.result.Rc2(end)))]);
                   else
                        plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc./app.optim.result.Rc,'r-',app.optim.result.z,app.optim.result.rc2./app.optim.result.Rc2,'g-',app.optim.result.z,app.optim.result.rc3./app.optim.result.Rc3,'b-');
                        legend(app.UIAxes_2,[strcat("dcf/Dcf = ",num2str(app.optim.result.rc(end)/app.optim.result.Rc(end))),strcat("dcf/Dcf = ",num2str(app.optim.result.rc2(end)/app.optim.result.Rc2(end))),strcat("dcf/Dcf = ",num2str(app.optim.result.rc3(end)/app.optim.result.Rc3(end)))]);
                   end
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'dc/Dc');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Draw tension'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.tau/9.81*1000);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Tension (g)');
                   legend(app.UIAxes_2,strcat('\tauf = ',num2str(app.optim.result.tau(end)/9.81*1000),' g'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Temperature'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.T-273,app.optim.result.z,app.optim.result.Tmax*arrayfun(app.optim.param.profil,app.optim.result.z).*(app.optim.result.z<=app.optim.param.Lfour)+app.optim.param.Text*(app.optim.result.z>app.optim.param.Lfour));
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   legend(app.UIAxes_2,'Preform','Furnace');
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'T (°C)');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Rc1/Rcontact'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc./app.optim.result.rcontact);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Rc1/Rcontact');
                   legend(app.UIAxes_2,'hide');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Schematic'))
                   app.zmSlider_2Label.Visible = 'on';
                   app.zmSlider_2.Limits = [0 app.optim.result.z(end)];
                   app.zmSlider_2.MajorTicks=linspace(0,app.optim.result.z(end),11);
                   app.zmSlider_2.Visible = 'on';
                   z=app.zmSlider_2.Value;
                   cla(app.UIAxes_2);
                   [A,pos]=min(abs(z-app.optim.result.z));
                   legend(app.UIAxes_2,'hide');
                   xlabel(app.UIAxes_2,'x (µm)');
                   ylabel(app.UIAxes_2,'y (µm)');
                   app.graph2.r0=rectangle(app.UIAxes_2,'Position',[-app.optim.result.R(pos)*1E6,-app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                   app.graph2.r0bis=rectangle(app.UIAxes_2,'Position',[-app.optim.result.r(pos)*1E6,-app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                   for j=1:app.optim.param.n
                      if (isreal(app.optim.result.Rc(pos))) && (~isnan(app.optim.result.Rc(pos))) && (isreal(app.optim.result.rc(pos))) && (~isnan(app.optim.result.rc(pos))) 
                          xcenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                          ycenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                          app.graph2.r(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.Rc(pos)*1E6,ycenter-app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                          app.graph2.rbis(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.rc(pos)*1E6,ycenter-app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                          if (app.optim.result.nb_nested>1)
                                if (isreal(app.optim.result.Rc2(pos))) && (~isnan(app.optim.result.Rc2(pos))) && (isreal(app.optim.result.rc2(pos))) && (~isnan(app.optim.result.rc2(pos)))
                                  xcenter=(app.optim.result.r(pos)-app.optim.result.Rc2(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                                  ycenter=(app.optim.result.r(pos)-app.optim.result.Rc2(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                                  app.graph2.r2(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.Rc2(pos)*1E6,ycenter-app.optim.result.Rc2(pos)*1E6,2*app.optim.result.Rc2(pos)*1E6,2*app.optim.result.Rc2(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                                  app.graph2.rbis2(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.rc2(pos)*1E6,ycenter-app.optim.result.rc2(pos)*1E6,2*app.optim.result.rc2(pos)*1E6,2*app.optim.result.rc2(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                                  if (app.optim.result.nb_nested>2)
                                       if (isreal(app.optim.result.Rc3(pos))) && (~isnan(app.optim.result.Rc3(pos))) && (isreal(app.optim.result.rc3(pos))) && (~isnan(app.optim.result.rc3(pos)))
                                          xcenter=(app.optim.result.r(pos)-app.optim.result.Rc3(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos)-app.optim.result.Rc2(pos)+app.optim.result.rc2(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                                          ycenter=(app.optim.result.r(pos)-app.optim.result.Rc3(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos)-app.optim.result.Rc2(pos)+app.optim.result.rc2(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                                          app.graph2.r3(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.Rc3(pos)*1E6,ycenter-app.optim.result.Rc3(pos)*1E6,2*app.optim.result.Rc3(pos)*1E6,2*app.optim.result.Rc3(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                                          app.graph2.rbis3(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.rc3(pos)*1E6,ycenter-app.optim.result.rc3(pos)*1E6,2*app.optim.result.rc3(pos)*1E6,2*app.optim.result.rc3(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                                       end
                                  end
                               end
                           end
                       end
                   end
                   app.UIAxes_2.XLim = app.UIAxes_2.YLim;
                  daspect(app.UIAxes_2,[1,1,1])
               end
            else
                if (strcmp(value,'Temperature'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                    Lfour=app.Length_Furnace_EditField.Value;
                    L=app.SimulationLength_EditField.Value;
                    Tmax=(app.OptimTmaxmaxEditField.Value+app.OptimTmaxminEditField.Value)/2;
                    app.UIAxes_2.XLim = [0 L];
                   if ~isempty(app.paramini.furnace_profile)
                          z=linspace(0,L,1E5);
                          plot(app.UIAxes_2,z,Tmax*arrayfun(app.paramini.furnace_profile,z).*(z<=Lfour)+app.ExternalTemperature_EditField.Value*(z>Lfour));
                   else
                          z=linspace(Lfour,L,1E5);
                          plot(app.UIAxes_2,z,app.ExternalTemperature_EditField.Value*(z>=Lfour));
                   end
                          legend(app.UIAxes_2,'Temperature profile');
                          xlabel(app.UIAxes_2,'z (m)');
                          ylabel(app.UIAxes_2,'T (°C)');
                          daspect(app.UIAxes_2,'auto');
               end
            end
        end
        
        %Trace le profil de température dans l'onglet parameters
        function trace_furnace(app)
            Lfour=app.Length_Furnace_EditField.Value;
            cla(app.UIAxes_3);
            app.UIAxes_3.XLim = [0 Lfour];
            if ~isempty(app.paramini.furnace_profile)
                  z=linspace(0,Lfour,1E5);
                  plot(app.UIAxes_3,z,arrayfun(app.paramini.furnace_profile,z));
                  app.UIAxes_3.XTickMode = 'auto';
            end
            xlabel(app.UIAxes_3,'z (m)');
            ylabel(app.UIAxes_3,'T/Tmax');
            daspect(app.UIAxes_3,'auto');
        end

        %Trace les focntions dépendantes de la température dans les ongles
        %jacket et capillaries
        function trace_parameters(app)
            T=250:10:2500;
            parameter = app.GrandeurARepresenter_DropDown4.Value;
            parameter2 = app.GrandeurARepresenter_DropDown5.Value;
            if strcmp(parameter,"Emissivity")
                cla(app.UIAxes_4);
                if ~isempty(app.paramini.alpha)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.alpha,T));
                    ylabel(app.UIAxes_4,'Emissivity');
                    app.UIAxes_4.YScale = 'linear';
                end
                legend(app.UIAxes_4,'hide');
            end
            if strcmp(parameter,"Viscosity")
                cla(app.UIAxes_4);
                hold(app.UIAxes_4,'on');
                vector_legend=[];
                if ~isempty(app.paramini.viscosity)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.viscosity,T),'k-');
                    vector_legend=[vector_legend,"Jacket"];
                end
                if ~isempty(app.paramini.viscosity_1)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.viscosity_1,T),'r--');
                    vector_legend=[vector_legend,"Capillaries #1"];
                end
                if ((~isempty(app.paramini.viscosity_2)) & (app.NestedCapillaries_Spinner.Value>1))
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.viscosity_2,T),'g--');
                    vector_legend=[vector_legend,"Capillaries #2"];
                end
                if ((~isempty(app.paramini.viscosity_3)) & (app.NestedCapillaries_Spinner.Value>2))
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.viscosity_3,T),'b--');
                    vector_legend=[vector_legend,"Capillaries #3"];
                end
                legend(app.UIAxes_4,vector_legend)
                ylabel(app.UIAxes_4,'Viscosity (N.s/m²)');
                app.UIAxes_4.YScale = 'log';
            end
            if strcmp(parameter,"Specific heat")
                cla(app.UIAxes_4);
                if ~isempty(app.paramini.Cp)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.Cp,T));
                    ylabel(app.UIAxes_4,'Specific heat (J/kg/K)');
                    app.UIAxes_4.YScale = 'linear';
                end
                legend(app.UIAxes_4,'hide');
            end
            if strcmp(parameter,"Volumic mass")
                cla(app.UIAxes_4);
                if ~isempty(app.paramini.rho)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.rho,T));
                    ylabel(app.UIAxes_4,'Volumic mass (kg/m^3)');
                    app.UIAxes_4.YScale = 'linear';
                end
                legend(app.UIAxes_4,'hide');
            end
            if strcmp(parameter,"Surface tension")
                cla(app.UIAxes_4);
                hold(app.UIAxes_4,'on');
                vector_legend=[];
                if ~isempty(app.paramini.gamma)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.gamma,T),'k-');
                    vector_legend=[vector_legend,"Jacket"];
                end
                if ~isempty(app.paramini.gamma_1)
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.gamma_1,T),'r--');
                    vector_legend=[vector_legend,"Capillaries #1"];
                end
                if ((~isempty(app.paramini.gamma_2)) & (app.NestedCapillaries_Spinner.Value>1))
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.gamma_2,T),'g--');
                    vector_legend=[vector_legend,"Capillaries #2"];
                end
                if ((~isempty(app.paramini.gamma_3)) & (app.NestedCapillaries_Spinner.Value>2))
                    plot(app.UIAxes_4,T,arrayfun(app.paramini.gamma_3,T),'b--');
                    vector_legend=[vector_legend,"Capillaries #3"];
                end
                legend(app.UIAxes_4,vector_legend)
                ylabel(app.UIAxes_4,'Surface Tension \gamma (N/m)');
            end

            if strcmp(parameter2,"Viscosity")
                cla(app.UIAxes_5);
                hold(app.UIAxes_5,'on');
                vector_legend=[];
                if ~isempty(app.paramini.viscosity)
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.viscosity,T),'k-');
                    vector_legend=[vector_legend,"Jacket"];
                end
                if ~isempty(app.paramini.viscosity_1)
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.viscosity_1,T),'r--');
                    vector_legend=[vector_legend,"Capillaries #1"];
                end
                if ((~isempty(app.paramini.viscosity_2)) & (app.NestedCapillaries_Spinner.Value>1))
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.viscosity_2,T),'g--');
                    vector_legend=[vector_legend,"Capillaries #2"];
                end
                if ((~isempty(app.paramini.viscosity_3)) & (app.NestedCapillaries_Spinner.Value>2))
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.viscosity_3,T),'b--');
                    vector_legend=[vector_legend,"Capillaries #3"];
                end
                legend(app.UIAxes_5,vector_legend)
                ylabel(app.UIAxes_5,'Viscosity (N.s/m²)');
                app.UIAxes_5.YScale = 'log';
            end
            if strcmp(parameter2,"Surface tension")
                cla(app.UIAxes_5);
                hold(app.UIAxes_5,'on');
                vector_legend=[];
                if ~isempty(app.paramini.gamma)
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.gamma,T),'k-');
                    vector_legend=[vector_legend,"Jacket"];
                end
                if ~isempty(app.paramini.gamma_1)
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.gamma_1,T),'r--');
                    vector_legend=[vector_legend,"Capillaries #1"];
                end
                if ((~isempty(app.paramini.gamma_2)) & (app.NestedCapillaries_Spinner.Value>1))
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.gamma_2,T),'g--');
                    vector_legend=[vector_legend,"Capillaries #2"];
                end
                if ((~isempty(app.paramini.gamma_3)) & (app.NestedCapillaries_Spinner.Value>2))
                    plot(app.UIAxes_5,T,arrayfun(app.paramini.gamma_3,T),'b--');
                    vector_legend=[vector_legend,"Capillaries #3"];
                end
                legend(app.UIAxes_5,vector_legend)
                ylabel(app.UIAxes_5,'Surface Tension \gamma (N/m)');
                app.UIAxes_5.YScale = 'linear';
            end
                
        end

        %Sauvegarde tous les réglages dans un fichier
        function save_param(app,file)
            param_save.Pcore=app.Pcore_EditField.Value;
            param_save.Tmax=app.Tmax_EditField.Value;
            param_save.Pcap1=app.DeltaP1_EditField.Value;
            param_save.Pcap2=app.DeltaP2_EditField.Value;
            param_save.Pcap3=app.DeltaP3_EditField.Value;

            param_save.Tmax_min=app.OptimTmaxminEditField.Value;
            param_save.Tmax_max=app.OptimTmaxmaxEditField.Value;
            param_save.Pcore_min=app.OptimPcoreminEditField.Value;
            param_save.Pcore_max=app.OptimPcoremaxEditField.Value;
            param_save.Pcap1_min=app.OptimDeltaPminEditField.Value;
            param_save.Pcap1_max=app.OptimDeltaPmaxEditField.Value;
            param_save.Pcap2_min=app.OptimDeltaPminEditField_2.Value;
            param_save.Pcap2_max=app.OptimDeltaPmaxEditField_2.Value;
            param_save.Pcap3_min=app.OptimDeltaPminEditField_3.Value;
            param_save.Pcap3_max=app.OptimDeltaPmaxEditField_3.Value;
            param_save.Optimtf=app.OptimtfEditField.Value;
            param_save.OptimDcf=app.OptimDcfEditField.Value;

            param_save.profil=app.TemperatureProfile_Furnace_EditField.Value;
            param_save.Lfour=app.Length_Furnace_EditField.Value;
            param_save.w0=app.FeedSpeed_EditField.Value;
            param_save.tension=app.DrawTension_EditField.Value;
            param_save.sigma=app.StefanBoltzmannConstant_EditField.Value;
            param_save.N=app.NewtonHeatTransferCoefficient_EditField.Value;
            param_save.g=app.GravitationalAcceleration_EditField.Value;
            param_save.dT0=app.DeltaT_EditField.Value;
            param_save.Text=app.ExternalTemperature_EditField.Value;
            param_save.L=app.SimulationLength_EditField.Value;

            param_save.D0=app.D0_Jacket_EditField.Value;
            param_save.d0=app.d0_Jacket_EditField.Value;
            param_save.rho=app.VolumicMass_Jacket_EditField.Value;
            param_save.gamma=app.SurfaceTension_Jacket_EditField.Value;
            param_save.Cp=app.SpecificHeat_Jacket_EditField.Value;
            param_save.viscosity=app.Viscosity_Jacket_EditField.Value;
            param_save.alpha=app.Emissivity_Jacket_EditField.Value;

            param_save.NbCapillaries=app.NumberOfCapillaries_Spinner.Value;
            param_save.NbNested=app.NestedCapillaries_Spinner.Value;
            param_save.Dc0_1=app.Dc0_Capillaries_EditField.Value;
            param_save.dc0_1=app.dc0_Capillaries_EditField.Value;
            param_save.Dc0_2=app.Dc0_Capillaries_EditField_2.Value;
            param_save.dc0_2=app.dc0_Capillaries_EditField_2.Value;
            param_save.Dc0_3=app.Dc0_Capillaries_EditField_3.Value;
            param_save.dc0_3=app.dc0_Capillaries_EditField_3.Value;
            param_save.gamma_1=app.SurfaceTension_Capillaries_EditField.Value;
            param_save.viscosity_1=app.Viscosity_Capillaries_EditField.Value;
            param_save.gamma_2=app.SurfaceTension_Capillaries_EditField_2.Value;
            param_save.viscosity_2=app.Viscosity_Capillaries_EditField_2.Value;
            param_save.gamma_3=app.SurfaceTension_Capillaries_EditField_3.Value;
            param_save.viscosity_3=app.Viscosity_Capillaries_EditField_3.Value;
            save(file,'param_save');
        end

        %Charge tous les réglages depuis un fichier
        function load_param(app,file)
            load(file,'param_save');
            app.Pcore_EditField.Value=param_save.Pcore;
            app.Tmax_EditField.Value=param_save.Tmax;
            app.DeltaP1_EditField.Value=param_save.Pcap1;
            app.DeltaP2_EditField.Value=param_save.Pcap2;
            app.DeltaP3_EditField.Value=param_save.Pcap3;

            app.OptimTmaxminEditField.Value=param_save.Tmax_min;
            app.OptimTmaxmaxEditField.Value=param_save.Tmax_max;
            app.OptimPcoreminEditField.Value=param_save.Pcore_min;
            app.OptimPcoremaxEditField.Value=param_save.Pcore_max;
            app.OptimDeltaPminEditField.Value=param_save.Pcap1_min;
            app.OptimDeltaPmaxEditField.Value=param_save.Pcap1_max;
            app.OptimDeltaPminEditField_2.Value=param_save.Pcap2_min;
            app.OptimDeltaPmaxEditField_2.Value=param_save.Pcap2_max;
            app.OptimDeltaPminEditField_3.Value=param_save.Pcap3_min;
            app.OptimDeltaPmaxEditField_3.Value=param_save.Pcap3_max;
            app.OptimtfEditField.Value=param_save.Optimtf;
            app.OptimDcfEditField.Value=param_save.OptimDcf;

            app.TemperatureProfile_Furnace_EditField.Value=param_save.profil;
            app.Length_Furnace_EditField.Value=param_save.Lfour;
            app.FeedSpeed_EditField.Value=param_save.w0;
            app.DrawTension_EditField.Value=param_save.tension;
            app.StefanBoltzmannConstant_EditField.Value=param_save.sigma;
            app.NewtonHeatTransferCoefficient_EditField.Value=param_save.N;
            app.GravitationalAcceleration_EditField.Value=param_save.g;
            app.DeltaT_EditField.Value=param_save.dT0;
            app.ExternalTemperature_EditField.Value=param_save.Text;
            app.SimulationLength_EditField.Value=param_save.L;

            app.D0_Jacket_EditField.Value=param_save.D0;
            app.d0_Jacket_EditField.Value=param_save.d0;
            app.VolumicMass_Jacket_EditField.Value=param_save.rho;
            app.SurfaceTension_Jacket_EditField.Value=param_save.gamma;
            app.SpecificHeat_Jacket_EditField.Value=param_save.Cp;
            app.Viscosity_Jacket_EditField.Value=param_save.viscosity;
            app.Emissivity_Jacket_EditField.Value=param_save.alpha;

            app.NumberOfCapillaries_Spinner.Value=param_save.NbCapillaries;
            app.NestedCapillaries_Spinner.Value=param_save.NbNested;
            app.Dc0_Capillaries_EditField.Value=param_save.Dc0_1;
            app.dc0_Capillaries_EditField.Value=param_save.dc0_1;
            app.Dc0_Capillaries_EditField_2.Value=param_save.Dc0_2;
            app.dc0_Capillaries_EditField_2.Value=param_save.dc0_2;
            app.Dc0_Capillaries_EditField_3.Value=param_save.Dc0_3;
            app.dc0_Capillaries_EditField_3.Value=param_save.dc0_3;
            app.SurfaceTension_Capillaries_EditField.Value=param_save.gamma_1;
            app.Viscosity_Capillaries_EditField.Value=param_save.viscosity_1;
            app.SurfaceTension_Capillaries_EditField_2.Value=param_save.gamma_2;
            app.Viscosity_Capillaries_EditField_2.Value=param_save.viscosity_2;
            app.SurfaceTension_Capillaries_EditField_3.Value=param_save.gamma_3;
            app.Viscosity_Capillaries_EditField_3.Value=param_save.viscosity_3;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning off;
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.Name = 'Tubular anti-resonant hollow-core fibers drawing simulation v1.5';
            parpool("threads");

            app.UIAxes_4.XLim = [250 2500];
            app.UIAxes_4.XTick = 250:250:2500;
            app.UIAxes_5.XLim = [250 2500];
            app.UIAxes_5.XTick = 250:250:2500;

            Emissivity_Jacket_EditFieldValueChanged(app, []);
            Viscosity_Jacket_EditFieldValueChanged(app, []);
            SurfaceTension_Jacket_EditFieldValueChanged(app,[]);
            VolumicMass_Jacket_EditFieldValueChanged(app,[]);
            SpecificHeat_Jacket_EditFieldValueChanged(app,[]);
            SurfaceTension_Capillaries_EditFieldValueChanged(app,[]);
            Viscosity_Capillaries_EditFieldValueChanged(app, []);
                      
            app.calcul_fait=false;
            app.optim_fait=false;

            if isfile(strcat(userpath,'\param.mat'))
                load_param(app,strcat(userpath,'\param.mat'))
            end
                
            compute_d0D0(app)
            compute_dc01Dc01(app);
            compute_dc02Dc02(app);
            compute_dc03Dc03(app);
            update_optim_objectifs(app);

            app.paramini.gamma=[];
            app.paramini.gamma_1=[];
            app.paramini.gamma_2=[];
            app.paramini.gamma_3=[];

            TemperatureProfile_Furnace_EditFieldValueChanged(app, true);

            VolumicMass_Jacket_EditFieldValueChanged(app,true);
            SurfaceTension_Jacket_EditFieldValueChanged(app,true);
            SpecificHeat_Jacket_EditFieldValueChanged(app,true);
            Viscosity_Jacket_EditFieldValueChanged(app,true);
            Emissivity_Jacket_EditFieldValueChanged(app,true);

            SurfaceTension_Capillaries_EditFieldValueChanged(app,true);
            SurfaceTension_Capillaries_EditField_2ValueChanged(app,true);
            SurfaceTension_Capillaries_EditField_3ValueChanged(app,true);
            Viscosity_Capillaries_EditFieldValueChanged(app,true);
            Viscosity_Capillaries_EditField_2ValueChanged(app,true);
            Viscosity_Capillaries_EditField_3ValueChanged(app,true);
            NestedCapillaries_SpinnerValueChanged(app,true);
            
            
            app.GrandeurARepresenter_DropDown1.Value='Temperature';
            app.GrandeurAReprrsenter_DropDown2.Value='Temperature';
            trace(app);
            trace_optim(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if (app.D0_Jacket_EditField.Value<=app.d0_Jacket_EditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'D0<=d0','Input Error');
                return;
            end
            if (app.Dc0_Capillaries_EditField.Value<=app.dc0_Capillaries_EditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc01<=dc01','Input Error');
                return;
            end
            nb_nested=app.NestedCapillaries_Spinner.Value;
            if (nb_nested>1)
                if (app.Dc0_Capillaries_EditField_2.Value<=app.dc0_Capillaries_EditField_2.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc02<=dc02','Input Error');
                    return;
                end
                if (app.Dc0_Capillaries_EditField_2.Value>=app.dc0_Capillaries_EditField.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc02>=dc01','Input Error');
                    return;
                end
            end
            if (nb_nested>2)
                if (app.Dc0_Capillaries_EditField_3.Value<=app.dc0_Capillaries_EditField_3.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc03<=dc03','Input Error');
                    return;
                end
                if (app.Dc0_Capillaries_EditField_3.Value>=app.dc0_Capillaries_EditField_2.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc03>=dc02','Input Error');
                    return;
                end
            end
            n=app.NumberOfCapillaries_Spinner.Value;
            rcontact=app.d0_Jacket_EditField.Value/2*sin(pi/n)/(1+sin(pi/n));
            if (app.Dc0_Capillaries_EditField.Value/2>rcontact)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Rc0>Rcontact','Input Error');
                return;
            end
                       
            if ~isempty(app.paramini.rho)
                app.param.rho=app.paramini.rho;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The volumic mass is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.gamma)
                app.param.gamma=app.paramini.gamma;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.gamma_1)
                app.param.gamma_1=app.paramini.gamma_1;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension for capillaries #1 is not defined','Input Error');
                return;
            end
            if (nb_nested>1)
                if ~isempty(app.paramini.gamma_2)
                    app.param.gamma_2=app.paramini.gamma_2;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension for capillaries #2 is not defined','Input Error');
                    return;
                end
            end
            if (nb_nested>2)
                if ~isempty(app.paramini.gamma_3)
                    app.param.gamma_3=app.paramini.gamma_3;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension for capillaries #3 is not defined','Input Error');
                    return;
                end
            end
            if ~isempty(app.paramini.Cp)
                app.param.Cp=app.paramini.Cp;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The specific heat is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.alpha)
                app.param.alpha=app.paramini.alpha;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The emissivity is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.viscosity)
                app.param.viscosity=app.paramini.viscosity;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined','Input Error');
                return;
            end  
            if ~isempty(app.paramini.viscosity_1)
                app.param.viscosity_1=app.paramini.viscosity_1;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined for capillaries #1','Input Error');
                return;
            end  
            if (nb_nested>1)
                if ~isempty(app.paramini.viscosity_2)
                    app.param.viscosity_2=app.paramini.viscosity_2;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined for capillaries #2','Input Error');
                    return;
                end 
            end
            if (nb_nested>2)
                if ~isempty(app.paramini.viscosity_3)
                    app.param.viscosity_3=app.paramini.viscosity_3;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined for capillaries #3','Input Error');
                    return;
                end 
            end
            if ~isempty(app.paramini.furnace_profile)
                app.param.profil=app.paramini.furnace_profile;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The furnace profile is not defined','Input Error');
                return;
            end  
            app.param.sigma=app.StefanBoltzmannConstant_EditField.Value;
            app.param.N=app.NewtonHeatTransferCoefficient_EditField.Value;
            app.param.g=app.GravitationalAcceleration_EditField.Value;
            app.param.dT0=app.DeltaT_EditField.Value;
            app.param.Text=app.ExternalTemperature_EditField.Value;

            d=uiprogressdlg(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Title','Computing solution...', 'Indeterminate','on');

            app.param.pcore=app.Pcore_EditField.Value*100;
            app.param.r0=app.d0_Jacket_EditField.Value/2*1E-3;
            app.param.R0=app.D0_Jacket_EditField.Value/2*1E-3;
            app.param.w0=app.FeedSpeed_EditField.Value*1E-3/60;
            app.param.n=app.NumberOfCapillaries_Spinner.Value;
            app.param.nb_nested=nb_nested;
            app.param.Rc0=app.Dc0_Capillaries_EditField.Value/2*1E-3;
            app.param.rc0=app.dc0_Capillaries_EditField.Value/2*1E-3;
            app.param.tension=app.DrawTension_EditField.Value/1000*9.81;
            app.param.L=app.SimulationLength_EditField.Value;
            app.param.Tmax=app.Tmax_EditField.Value;
            app.param.Pcap=app.DeltaP1_EditField.Value*100;
            if (nb_nested>1)
                app.param.Rc02=app.Dc0_Capillaries_EditField_2.Value/2*1E-3;
                app.param.rc02=app.dc0_Capillaries_EditField_2.Value/2*1E-3;
                app.param.Pcap2=app.DeltaP2_EditField.Value*100;
                app.param.DeltaP2=app.param.Pcap2-app.param.Pcap;
            end
            if (nb_nested>2)
                app.param.Rc03=app.Dc0_Capillaries_EditField_3.Value/2*1E-3;
                app.param.rc03=app.dc0_Capillaries_EditField_3.Value/2*1E-3;
                app.param.Pcap3=app.DeltaP3_EditField.Value*100;
                app.param.DeltaP3=app.param.Pcap3-app.param.Pcap2;
            end
            app.param.DeltaP=app.param.Pcap-app.param.pcore;
            app.param.Lfour=app.Length_Furnace_EditField.Value;

            %Sauvegarde avant résolution
            save_param(app,strcat(userpath,'\param.mat'));

            T0=app.param.Tmax*app.param.profil(0)+273-app.param.dT0;
            options_ode = odeset('Reltol',1e-10);
            x=optim_tension(app.param,app.param.Tmax,app.param.pcore,1E-10);

            if (nb_nested==1)
                fct=@(t,y) RK_perso7(app.param,t,y,app.param.Tmax,app.param.pcore,app.param.DeltaP);
                app.result.sol1=ode45(fct,[0 app.param.Lfour],[x,app.param.w0,app.param.R0^2*app.param.w0,app.param.r0^2*app.param.w0,T0,app.param.Rc0^2*app.param.w0,app.param.rc0^2*app.param.w0],options_ode);
                fct=@(t,y) RK_perso7_293K(app.param,t,y,app.param.pcore,app.param.DeltaP);
                app.result.sol2=ode45(fct,[app.param.Lfour app.param.L],app.result.sol1.y(:,end),options_ode);
            elseif (nb_nested==2)
                fct=@(t,y) RK_perso9(app.param,t,y,app.param.Tmax,app.param.pcore,app.param.DeltaP,app.param.DeltaP2);
                app.result.sol1=ode45(fct,[0 app.param.Lfour],[x,app.param.w0,app.param.R0^2*app.param.w0,app.param.r0^2*app.param.w0,T0,app.param.Rc0^2*app.param.w0,app.param.rc0^2*app.param.w0,app.param.Rc02^2*app.param.w0,app.param.rc02^2*app.param.w0],options_ode);
                fct=@(t,y) RK_perso9_293K(app.param,t,y,app.param.pcore,app.param.DeltaP,app.param.DeltaP2);
                app.result.sol2=ode45(fct,[app.param.Lfour app.param.L],app.result.sol1.y(:,end),options_ode);
            else
                fct=@(t,y) RK_perso11(app.param,t,y,app.param.Tmax,app.param.pcore,app.param.DeltaP,app.param.DeltaP2,app.param.DeltaP3);
                app.result.sol1=ode45(fct,[0 app.param.Lfour],[x,app.param.w0,app.param.R0^2*app.param.w0,app.param.r0^2*app.param.w0,T0,app.param.Rc0^2*app.param.w0,app.param.rc0^2*app.param.w0,app.param.Rc02^2*app.param.w0,app.param.rc02^2*app.param.w0,app.param.Rc03^2*app.param.w0,app.param.rc03^2*app.param.w0],options_ode);
                fct=@(t,y) RK_perso11_293K(app.param,t,y,app.param.pcore,app.param.DeltaP,app.param.DeltaP2,app.param.DeltaP3);
                app.result.sol2=ode45(fct,[app.param.Lfour app.param.L],app.result.sol1.y(:,end),options_ode);
            end

            
            nb1=ceil(max(app.result.sol1.x)/1E-5)+1;
            z1=linspace(0,max(app.result.sol1.x),nb1);
            y1=deval(app.result.sol1,z1);
            nb2=ceil((max(app.result.sol2.x)-app.param.Lfour)/1E-5)+1;
            z2=linspace(app.param.Lfour,max(app.result.sol2.x),nb2);
            y2=deval(app.result.sol2,z2);
            app.result.z=[z1,z2(2:end)];
            app.result.tau=[y1(1,:),y2(1,2:end)];
            app.result.w=[y1(2,:),y2(2,2:end)];
            app.result.R2w=[y1(3,:),y2(3,2:end)];
            app.result.r2w=[y1(4,:),y2(4,2:end)];
            app.result.T=[y1(5,:),y2(5,2:end)];
                app.result.Rc2w=[y1(6,:),y2(6,2:end)];
                app.result.rc2w=[y1(7,:),y2(7,2:end)];
                app.result.Rc=sqrt(app.result.Rc2w./app.result.w);
                app.result.rc=sqrt(app.result.rc2w./app.result.w);
            if (nb_nested>1)
                app.result.Rc2w2=[y1(8,:),y2(8,2:end)];
                app.result.rc2w2=[y1(9,:),y2(9,2:end)];
                app.result.Rc2=sqrt(app.result.Rc2w2./app.result.w);
                app.result.rc2=sqrt(app.result.rc2w2./app.result.w);
            end
            if (nb_nested>2)
                app.result.Rc2w3=[y1(10,:),y2(10,2:end)];
                app.result.rc2w3=[y1(11,:),y2(11,2:end)];
                app.result.Rc3=sqrt(app.result.Rc2w3./app.result.w);
                app.result.rc3=sqrt(app.result.rc2w3./app.result.w);
            end
            app.result.nb_nested=nb_nested;
            app.result.R=sqrt(app.result.R2w./app.result.w);
            app.result.r=sqrt(app.result.r2w./app.result.w);
            app.result.mu=arrayfun(app.param.viscosity,app.result.T);
            app.result.dwdz=app.result.tau./(3*pi*app.result.mu.*(app.result.R.^2-app.result.r.^2));
            app.result.rcontact=app.result.r*sin(pi/app.param.n)/(1+sin(pi/app.param.n));
            app.calcul_fait=true;
            app.SaveresultsButton.Enable = 'on';
            close(d);
            app.dfLabel.Text=strcat('df = ',num2str(app.result.r(end)*2*1E6),' µm');
            app.DfLabel.Text=strcat('Df = ',num2str(app.result.R(end)*2*1E6),' µm');
                app.dcf1Label.Text=strcat('dcf1 = ',num2str(app.result.rc(end)*2*1E6),' µm');
                app.Dcf1Label.Text=strcat('Dcf1 = ',num2str(app.result.Rc(end)*2*1E6),' µm');
                app.DcoreLabel.Text=strcat('Dcore = ',num2str((app.result.r(end)-2*app.result.Rc(end))*2*1E6),' µm');
                app.tf1Label.Text=strcat('tf1 = ',num2str((app.result.Rc(end)-app.result.rc(end))*1E6),' µm');
                app.VfLabel.Text=strcat('Vf = ',num2str(app.result.w(end)*60),' m/min');
                app.dcf1Dcf1Label_2.Text=strcat('dcf1/Dcf1 = ',num2str(app.result.rc(end)/app.result.Rc(end)));
                app.maxRc1RcontactLabel.Interpreter='html';
                if (max(app.result.Rc./app.result.rcontact)>=1)
                    app.maxRc1RcontactLabel.Text=strcat('max(Rc1/Rcontact) = <font color="red">',num2str(max(app.result.Rc./app.result.rcontact)),'</font>');
                else
                    app.maxRc1RcontactLabel.Text=strcat('max(Rc1/Rcontact) = <font color="green">',num2str(max(app.result.Rc./app.result.rcontact)),'</font>');
                end
                app.Rcf1RcontactLabel.Text=strcat('Rcf1/Rcontact = ',num2str(app.result.Rc(end)/app.result.rcontact(end)));

            app.maxDc2dc1Label.Interpreter='html';
            if (nb_nested>1)
                app.dcf2Label.Text=strcat('dcf2 = ',num2str(app.result.rc2(end)*2*1E6),' µm');
                app.Dcf2Label.Text=strcat('Dcf2 = ',num2str(app.result.Rc2(end)*2*1E6),' µm');
                app.tf2Label.Text=strcat('tf2 = ',num2str((app.result.Rc2(end)-app.result.rc2(end))*1E6),' µm');
                app.dcf2Dcf2Label_2.Text=strcat('dcf2/Dcf2 = ',num2str(app.result.rc2(end)/app.result.Rc2(end)));
                if (max(app.result.Rc2./app.result.rc)>=1)
                    app.maxDc2dc1Label.Text=strcat('max(Dc2/dc1) = <font color="red">',num2str(max(app.result.Rc2./app.result.rc)),'</font>');
                else
                    app.maxDc2dc1Label.Text=strcat('max(Dc2/dc1) = <font color="green">',num2str(max(app.result.Rc2./app.result.rc)),'</font>');
                end
            else
                app.dcf2Label.Text='dcf2 = ';
                app.Dcf2Label.Text='Dcf2 = ';
                app.tf2Label.Text='tf2 = ';
                app.dcf2Dcf2Label_2.Text='dcf2/Dcf2 = ';
                app.maxDc2dc1Label.Text='max(Dc2/dc1) =';
            end
            app.maxDc3dc2Label.Interpreter='html';
            if (nb_nested>2)
                app.dcf3Label.Text=strcat('dcf3 = ',num2str(app.result.rc3(end)*2*1E6),' µm');
                app.Dcf3Label.Text=strcat('Dcf3 = ',num2str(app.result.Rc3(end)*2*1E6),' µm');
                app.tf3Label.Text=strcat('tf3 = ',num2str((app.result.Rc3(end)-app.result.rc3(end))*1E6),' µm');
                app.dcf3Dcf3Label_2.Text=strcat('dcf3/Dcf3 = ',num2str(app.result.rc3(end)/app.result.Rc3(end)));
                if (max(app.result.Rc3./app.result.rc2)>=1)
                    app.maxDc3dc2Label.Text=strcat('max(Dc3/dc2) = <font color="red">',num2str(max(app.result.Rc3./app.result.rc2)),'</font>');
                else
                    app.maxDc3dc2Label.Text=strcat('max(Dc3/dc2) = <font color="green">',num2str(max(app.result.Rc3./app.result.rc2)),'</font>');
                end
            else
                app.dcf3Label.Text='dcf3 = ';
                app.Dcf3Label.Text='Dcf3 = ';
                app.tf3Label.Text='tf3 = ';
                app.dcf3Dcf3Label_2.Text='dcf3/Dcf3 = ';
                app.maxDc3dc2Label.Text='max(Dc3/dc2) =';
            end

            app.GrandeurARepresenter_DropDown1.Enable=true;
            app.zmSlider.Value=0;
            trace(app);
        end

        % Value changed function: GrandeurARepresenter_DropDown1
        function GrandeurARepresenter_DropDown1ValueChanged(app, event)
            %value = app.GrandeurARepresenter_DropDown1.Value;
            trace(app);
        end

        % Value changing function: zmSlider
        function zmSliderValueChanging(app, event)
            changingValue = event.Value;
            [A,pos]=min(abs(changingValue-app.result.z));
            if (isreal(app.result.R(pos))) && (~isnan(app.result.R(pos))) && (isreal(app.result.r(pos))) && (~isnan(app.result.r(pos)))
                app.graph1.r0.Position=[-app.result.R(pos)*1E6,-app.result.R(pos)*1E6,2*app.result.R(pos)*1E6,2*app.result.R(pos)*1E6];
                app.graph1.r0bis.Position=[-app.result.r(pos)*1E6,-app.result.r(pos)*1E6,2*app.result.r(pos)*1E6,2*app.result.r(pos)*1E6];
            end
            for j=1:app.param.n
                if (isreal(app.result.Rc(pos))) && (~isnan(app.result.Rc(pos))) && (isreal(app.result.rc(pos))) && (~isnan(app.result.rc(pos)))
                    xcenter=(app.result.r(pos)-app.result.Rc(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                    ycenter=(app.result.r(pos)-app.result.Rc(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                    app.graph1.r(j).Position=[xcenter-app.result.Rc(pos)*1E6,ycenter-app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6];
                    app.graph1.rbis(j).Position=[xcenter-app.result.rc(pos)*1E6,ycenter-app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6];
                    if (app.result.nb_nested>1)
                        if (isreal(app.result.Rc2(pos))) && (~isnan(app.result.Rc2(pos))) && (isreal(app.result.rc2(pos))) && (~isnan(app.result.rc2(pos)))
                            xcenter=(app.result.r(pos)-app.result.Rc2(pos)-app.result.Rc(pos)+app.result.rc(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                            ycenter=(app.result.r(pos)-app.result.Rc2(pos)-app.result.Rc(pos)+app.result.rc(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                            app.graph1.r2(j).Position=[xcenter-app.result.Rc2(pos)*1E6,ycenter-app.result.Rc2(pos)*1E6,2*app.result.Rc2(pos)*1E6,2*app.result.Rc2(pos)*1E6];
                            app.graph1.rbis2(j).Position=[xcenter-app.result.rc2(pos)*1E6,ycenter-app.result.rc2(pos)*1E6,2*app.result.rc2(pos)*1E6,2*app.result.rc2(pos)*1E6];
                            if (app.result.nb_nested>2)
                                if (isreal(app.result.Rc3(pos))) && (~isnan(app.result.Rc3(pos))) && (isreal(app.result.rc3(pos))) && (~isnan(app.result.rc3(pos)))
                                    xcenter=(app.result.r(pos)-app.result.Rc3(pos)-app.result.Rc(pos)+app.result.rc(pos)-app.result.Rc2(pos)+app.result.rc2(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                                    ycenter=(app.result.r(pos)-app.result.Rc3(pos)-app.result.Rc(pos)+app.result.rc(pos)-app.result.Rc2(pos)+app.result.rc2(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                                    app.graph1.r3(j).Position=[xcenter-app.result.Rc3(pos)*1E6,ycenter-app.result.Rc3(pos)*1E6,2*app.result.Rc3(pos)*1E6,2*app.result.Rc3(pos)*1E6];
                                    app.graph1.rbis3(j).Position=[xcenter-app.result.rc3(pos)*1E6,ycenter-app.result.rc3(pos)*1E6,2*app.result.rc3(pos)*1E6,2*app.result.rc3(pos)*1E6];
                                end
                            end
                        end
                    end
                end
            end
            app.UIAxes.XLim = app.UIAxes.YLim;
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            result=app.result;
            param=app.param;
            save(strcat(path,file),'result','param');
        end

        % Value changed function: OptimtfEditField
        function OptimtfEditFieldValueChanged(app, event)
            update_optim_objectifs(app);
        end

        % Value changed function: OptimDcfEditField
        function OptimDcfEditFieldValueChanged(app, event)
            update_optim_objectifs(app);
        end

        % Value changed function: OptimDcoreEditField
        function OptimDcoreEditFieldValueChanged(app, event)
            update_optim_objectifs(app);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            if (app.D0_Jacket_EditField.Value<=app.d0_Jacket_EditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'D0<=d0','Input Error');
                return;
            end
            if (app.Dc0_Capillaries_EditField.Value<=app.dc0_Capillaries_EditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc01<=dc01','Input Error');
                return;
            end
            n=app.NumberOfCapillaries_Spinner.Value;
            nb_nested=app.NestedCapillaries_Spinner.Value;
            if (nb_nested>1)
                if (app.Dc0_Capillaries_EditField_2.Value<=app.dc0_Capillaries_EditField_2.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc02<=dc02','Input Error');
                    return;
                end
                if (app.Dc0_Capillaries_EditField_2.Value>=app.dc0_Capillaries_EditField.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc02>=dc01','Input Error');
                    return;
                end
            end
            if (nb_nested>2)
                if (app.Dc0_Capillaries_EditField_3.Value<=app.dc0_Capillaries_EditField_3.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc03<=dc03','Input Error');
                    return;
                end
                if (app.Dc0_Capillaries_EditField_3.Value>=app.dc0_Capillaries_EditField_2.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc03>=dc02','Input Error');
                    return;
                end
            end
            rcontact=app.d0_Jacket_EditField.Value/2*sin(pi/n)/(1+sin(pi/n));
            if (app.Dc0_Capillaries_EditField.Value/2>rcontact)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Rc01>Rcontact','Input Error');
                return;
            end
            if(app.OptimDeltaPmaxEditField.Value<=app.OptimDeltaPminEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Pcap1_max<=Pcap1_min','Input Error');
                return;
            end
            if (nb_nested>1)
                if(app.OptimDeltaPmaxEditField_2.Value<=app.OptimDeltaPminEditField_2.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Pcap2_max<=Pcap2_min','Input Error');
                    return;
                end
            end
            if (nb_nested>2)
                if(app.OptimDeltaPmaxEditField_3.Value<=app.OptimDeltaPminEditField_3.Value)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Pcap3_max<=Pcap3_min','Input Error');
                    return;
                end
            end

            if(app.OptimTmaxmaxEditField.Value<=app.OptimTmaxminEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Tmax_max<=Tmax_min','Input Error');
                return;
            end
            if(app.OptimPcoremaxEditField.Value<=app.OptimPcoreminEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Pcore_max<=Pcore_min','Input Error');
                return;
            end
            
            if ~isempty(app.paramini.rho)
                app.optim.param.rho=app.paramini.rho;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The volumic mass is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.gamma)
                app.optim.param.gamma=app.paramini.gamma;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.gamma_1)
                app.optim.param.gamma_1=app.paramini.gamma_1;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension for capillaries #1 is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.Cp)
                app.optim.param.Cp=app.paramini.Cp;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The specific heat is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.alpha)
                app.optim.param.alpha=app.paramini.alpha;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The emissivity is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.viscosity)
                app.optim.param.viscosity=app.paramini.viscosity;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined','Input Error');
                return;
            end  
            if ~isempty(app.paramini.viscosity_1)
                app.optim.param.viscosity_1=app.paramini.viscosity_1;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity of cap #1 is not defined','Input Error');
                return;
            end  
            if (nb_nested>1)
                if ~isempty(app.paramini.gamma_2)
                    app.optim.param.gamma_2=app.paramini.gamma_2;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension for capillaries #2 is not defined','Input Error');
                    return;
                end
                if ~isempty(app.paramini.viscosity_2)
                    app.optim.param.viscosity_2=app.paramini.viscosity_2;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity of cap #2 is not defined','Input Error');
                    return;
                end  
            end
            if (nb_nested>2)
                if ~isempty(app.paramini.gamma_3)
                    app.optim.param.gamma_3=app.paramini.gamma_3;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension for capillaries #3 is not defined','Input Error');
                    return;
                end
                if ~isempty(app.paramini.viscosity_3)
                    app.optim.param.viscosity_3=app.paramini.viscosity_3;
                else
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity of cap #3 is not defined','Input Error');
                    return;
                end  
            end


            if ~isempty(app.paramini.furnace_profile)
                app.optim.param.profil=app.paramini.furnace_profile;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The furnace profile is not defined','Input Error');
                return;
            end  
            app.optim.param.sigma=app.StefanBoltzmannConstant_EditField.Value;
            app.optim.param.N=app.NewtonHeatTransferCoefficient_EditField.Value;
            app.optim.param.g=app.GravitationalAcceleration_EditField.Value;
            app.optim.param.dT0=app.DeltaT_EditField.Value;
            app.optim.param.Text=app.ExternalTemperature_EditField.Value;
            
            
            d=uiprogressdlg(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Title','Computing solution', 'Indeterminate','on');
            d.Message='Initialization';
            save_param(app,strcat(userpath,'\param.mat'));
            
            app.optim.param.r0=app.d0_Jacket_EditField.Value/2*1E-3;
            app.optim.param.R0=app.D0_Jacket_EditField.Value/2*1E-3;
            app.optim.param.w0=app.FeedSpeed_EditField.Value*1E-3/60;
            app.optim.param.n=app.NumberOfCapillaries_Spinner.Value;
            app.optim.nb_nested=app.NestedCapillaries_Spinner.Value;
            app.optim.param.Rc0=app.Dc0_Capillaries_EditField.Value/2*1E-3;
            app.optim.param.rc0=app.dc0_Capillaries_EditField.Value/2*1E-3;
            if (nb_nested>1)
                app.optim.param.Rc02=app.Dc0_Capillaries_EditField_2.Value/2*1E-3;
                app.optim.param.rc02=app.dc0_Capillaries_EditField_2.Value/2*1E-3;
            end
            if (nb_nested>2)
                app.optim.param.Rc03=app.Dc0_Capillaries_EditField_3.Value/2*1E-3;
                app.optim.param.rc03=app.dc0_Capillaries_EditField_3.Value/2*1E-3;
            end
            app.optim.param.tension=app.DrawTension_EditField.Value/1000*9.81;
            app.optim.param.L=app.SimulationLength_EditField.Value;
            app.optim.param.tf=app.OptimtfEditField.Value*1E-6;
            app.optim.param.Rcf=app.OptimDcfEditField.Value*1E-6/2;
            app.optim.param.Dcore=app.OptimDcoreEditField.Value*1E-6;
            app.optim.param.rcf=app.optim.param.Rcf-app.optim.param.tf;
            if (app.optim.param.rcf<=0)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'rcf<=0','Input Error');
                return;
            end
            app.optim.param.rf=app.optim.param.Dcore/2+2*app.optim.param.Rcf;
            app.optim.param.wf=(app.optim.param.Rc0^2-app.optim.param.rc0^2)/(app.optim.param.Rcf^2-app.optim.param.rcf^2)*app.optim.param.w0;
            if (nb_nested>1)
                app.optim.param.t02=(app.optim.param.Rc02-app.optim.param.rc02);
                app.optim.param.Rcf2=(app.optim.param.w0/app.optim.param.wf*(2*app.optim.param.Rc02*app.optim.param.t02-app.optim.param.t02^2)/app.optim.param.tf+app.optim.param.tf)/2;
                app.optim.param.rcf2=app.optim.param.Rcf2-app.optim.param.tf;
                if (app.optim.param.rcf2<=0)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'rcf2<=0','Input Error');
                    return;
                end
                if (app.optim.param.Rcf2>=app.optim.param.rcf)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dcf2>=Dcf1','Input Error');
                    return;
                end
            end
            if (nb_nested>2)
                app.optim.param.t03=(app.optim.param.Rc03-app.optim.param.rc03);
                app.optim.param.Rcf3=(app.optim.param.w0/app.optim.param.wf*(2*app.optim.param.Rc03*app.optim.param.t03-app.optim.param.t03^2)/app.optim.param.tf+app.optim.param.tf)/2;
                app.optim.param.rcf3=app.optim.param.Rcf3-app.optim.param.tf;
                if (app.optim.param.rcf3<=0)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'rcf3<=0','Input Error');
                    return;
                end
                if (app.optim.param.Rcf3>=app.optim.param.rcf2)
                    uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dcf3>=Dcf2','Input Error');
                    return;
                end
            end
            app.optim.param.Rf=sqrt((app.optim.param.R0^2-app.optim.param.r0^2)*app.optim.param.w0/app.optim.param.wf+app.optim.param.rf^2);
            app.optim.param.Lfour=app.Length_Furnace_EditField.Value;
            Pmin=app.OptimPcoreminEditField.Value*100;
            Pmax=app.OptimPcoremaxEditField.Value*100;
            Tmin=app.OptimTmaxminEditField.Value;
            Tmax=app.OptimTmaxmaxEditField.Value;
            %Tmax a de l'influence sur R mais peu sur R/r
            %Pcore a de l'influence sur R/r mais peu sur R
            %=>en première approx on peut faire un encadrement des deux grandeurs
            %=>puis optimisation sous contrainte
            %Optimisation à 10°C près
            if (Tmax-Tmin>20)
                d.Message='Approximation of Tmax'; 
                Tmin2=zeros(1,2);
                Tmax2=zeros(1,2);
                P=[Pmin,Pmax];
                param2=[app.optim.param,app.optim.param];
                parfor boucle=1:2
                    warning off;
                     [Tmin2(boucle),Tmax2(boucle)]=optimisation_T(param2(boucle),linspace(Tmin,Tmax,ceil((Tmax-Tmin)/10)+1),P(boucle));
                end
                Tmin=min(Tmin2);
                Tmax=max(Tmax2);
            end
            %Optimsisation à 1000 bars près
            if (Pmax-Pmin>2000)
                d.Message=strcat(num2str(Tmin),'<Tmax<',num2str(Tmax),'°C ; Approximation of Pcore 1/2');
                [Pmin1,Pmax1]=optimisation_P(app.optim.param,Tmin,linspace(Pmin,Pmax,ceil((Pmax-Pmin)/1000)+1));
                d.Message=strcat(num2str(Tmin),'<Tmax<',num2str(Tmax),'°C ; Approximation of Pcore 2/2');
                [Pmin2,Pmax2]=optimisation_P(app.optim.param,Tmax,linspace(Pmin,Pmax,ceil((Pmax-Pmin)/1000)+1));
                Pmin=min(Pmin1,Pmin2);
                Pmax=max(Pmax1,Pmax2);
            end
            d.Message=strcat('Optimization of the pair (Tmax,Pcore)');
            x0=[(Tmax+Tmin)/2,(Pmax+Pmin)/2];
            lb=[Tmin,Pmin];
            ub=[Tmax,Pmax];
            parambis=app.optim.param;
            func_multi=@(x) optimisation_TP(parambis,x);
            %Use parallel not working with global variable => parambis
            options=optimoptions('fmincon','Algorithm','interior-point','Display','none','StepTolerance',1E-10,'ObjectiveLimit',1E-5,'OptimalityTolerance',1E-5,'UseParallel',true);
            x=fmincon(func_multi,x0,[],[],[],[],lb,ub,[],options);
            app.OptimTmaxLabel.Text=strcat('Tmax = ',num2str(x(1)),'°C');
            app.OptimPcoreLabel.Text=strcat('Pcore = ',num2str(x(2)/100),' mbar');
            app.optim.result.Tmax=x(1);
            app.optim.result.Pcore=x(2);
            d.Message=strcat('Optimization of Pcap1');
            DeltaPmin=app.OptimDeltaPminEditField.Value*100-app.optim.result.Pcore;
            DeltaPmax=app.OptimDeltaPmaxEditField.Value*100-app.optim.result.Pcore;
            func=@(t) optim_DeltaP(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,t);
            lb=DeltaPmin;
            ub=DeltaPmax;
            options=optimset('Display','none','TolX',1E-1);
            dp=fminbnd(func,lb,ub,options);
            app.optim.result.DeltaP=dp;
            app.optim.result.Pcap=dp+app.optim.result.Pcore;
            app.OptimdpLabel.Text=strcat('Pcap = ',num2str(app.optim.result.Pcap/100,'%.2f'),' mbar');


            if (nb_nested>1)
                d.Message=strcat('Optimization of Pcap2');
                DeltaPmin=app.OptimDeltaPminEditField_2.Value*100-app.optim.result.Pcap;
                DeltaPmax=app.OptimDeltaPmaxEditField_2.Value*100-app.optim.result.Pcap;
                func=@(t) optim_DeltaP2(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,app.optim.result.Pcap-app.optim.result.Pcore,t);
                lb=DeltaPmin;
                ub=DeltaPmax;
                options=optimset('Display','none','TolX',1E-1);
                dp=fminbnd(func,lb,ub,options);
                app.optim.result.DeltaP2=dp;
                app.optim.result.Pcap2=dp+app.optim.result.Pcap;
                app.OptimdpLabel_2.Text=strcat('Pcap2 = ',num2str(app.optim.result.Pcap2/100,'%.2f'),' mbar');
            else
                app.OptimdpLabel_2.Text='Pcap2 = ';
            end

            if (nb_nested>2)
                d.Message=strcat('Optimization of Pcap3');
                DeltaPmin=app.OptimDeltaPminEditField_3.Value*100-app.optim.result.Pcap2;
                DeltaPmax=app.OptimDeltaPmaxEditField_3.Value*100-app.optim.result.Pcap2;
                func=@(t) optim_DeltaP3(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,app.optim.result.Pcap-app.optim.result.Pcore,app.optim.result.Pcap2-app.optim.result.Pcap,t);
                lb=DeltaPmin;
                ub=DeltaPmax;
                options=optimset('Display','none','TolX',1E-1);
                dp=fminbnd(func,lb,ub,options);
                app.optim.result.DeltaP3=dp;
                app.optim.result.Pcap3=dp+app.optim.result.Pcap2;
                app.OptimdpLabel_3.Text=strcat('Pcap3 = ',num2str(app.optim.result.Pcap3/100,'%.2f'),' mbar');
            else
                app.OptimdpLabel_3.Text='Pcap3 = ';
            end


            d.Message=strcat('Final computation');
            T0=app.optim.result.Tmax*app.optim.param.profil(0)+273-app.optim.param.dT0;
            if (nb_nested==1)
                fct=@(t,y) RK_perso7(app.optim.param,t,y,app.optim.result.Tmax,app.optim.result.Pcore,app.optim.result.DeltaP);
                x=optim_tension(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,1E-10);
                options_ode = odeset('Reltol',1e-10,'AbsTol',1E-15);
                app.optim.result.sol1=ode45(fct,[0 app.optim.param.Lfour],[x,app.optim.param.w0,app.optim.param.R0^2*app.optim.param.w0,app.optim.param.r0^2*app.optim.param.w0,T0,app.optim.param.Rc0^2*app.optim.param.w0,app.optim.param.rc0^2*app.optim.param.w0],options_ode);
                fct=@(t,y) RK_perso7_293K(app.optim.param,t,y,app.optim.result.Pcore,app.optim.result.DeltaP);
                app.optim.result.sol2=ode45(fct,[app.optim.param.Lfour app.optim.param.L],app.optim.result.sol1.y(:,end),options_ode);
            elseif (nb_nested==2)
                fct=@(t,y) RK_perso9(app.optim.param,t,y,app.optim.result.Tmax,app.optim.result.Pcore,app.optim.result.DeltaP,app.optim.result.DeltaP2);
                x=optim_tension(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,1E-10);
                options_ode = odeset('Reltol',1e-10,'AbsTol',1E-15);
                app.optim.result.sol1=ode45(fct,[0 app.optim.param.Lfour],[x,app.optim.param.w0,app.optim.param.R0^2*app.optim.param.w0,app.optim.param.r0^2*app.optim.param.w0,T0,app.optim.param.Rc0^2*app.optim.param.w0,app.optim.param.rc0^2*app.optim.param.w0,app.optim.param.Rc02^2*app.optim.param.w0,app.optim.param.rc02^2*app.optim.param.w0],options_ode);
                fct=@(t,y) RK_perso9_293K(app.optim.param,t,y,app.optim.result.Pcore,app.optim.result.DeltaP,app.optim.result.DeltaP2);
                app.optim.result.sol2=ode45(fct,[app.optim.param.Lfour app.optim.param.L],app.optim.result.sol1.y(:,end),options_ode);
            else
                fct=@(t,y) RK_perso11(app.optim.param,t,y,app.optim.result.Tmax,app.optim.result.Pcore,app.optim.result.DeltaP,app.optim.result.DeltaP2,app.optim.result.DeltaP3);
                x=optim_tension(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,1E-10);
                options_ode = odeset('Reltol',1e-10,'AbsTol',1E-15);
                app.optim.result.sol1=ode45(fct,[0 app.optim.param.Lfour],[x,app.optim.param.w0,app.optim.param.R0^2*app.optim.param.w0,app.optim.param.r0^2*app.optim.param.w0,T0,app.optim.param.Rc0^2*app.optim.param.w0,app.optim.param.rc0^2*app.optim.param.w0,app.optim.param.Rc02^2*app.optim.param.w0,app.optim.param.rc02^2*app.optim.param.w0,app.optim.param.Rc03^2*app.optim.param.w0,app.optim.param.rc03^2*app.optim.param.w0],options_ode);
                fct=@(t,y) RK_perso11_293K(app.optim.param,t,y,app.optim.result.Pcore,app.optim.result.DeltaP,app.optim.result.DeltaP2,app.optim.result.DeltaP3);
                app.optim.result.sol2=ode45(fct,[app.optim.param.Lfour app.optim.param.L],app.optim.result.sol1.y(:,end),options_ode);
            end

            
            nb1=ceil(app.optim.param.Lfour/1E-5)+1;
            z1=linspace(0,app.optim.param.Lfour,nb1);
            y1=deval(app.optim.result.sol1,z1);
            nb2=ceil((app.optim.param.L-app.optim.param.Lfour)/1E-5)+1;
            z2=linspace(app.optim.param.Lfour,app.optim.param.L,nb2);
            y2=deval(app.optim.result.sol2,z2);
            app.optim.result.z=[z1,z2(2:end)];
            app.optim.result.nb_nested=nb_nested;
            app.optim.result.tau=[y1(1,:),y2(1,2:end)];
            app.optim.result.w=[y1(2,:),y2(2,2:end)];
            app.optim.result.R2w=[y1(3,:),y2(3,2:end)];
            app.optim.result.r2w=[y1(4,:),y2(4,2:end)];
            app.optim.result.T=[y1(5,:),y2(5,2:end)];
            app.optim.result.Rc2w=[y1(6,:),y2(6,2:end)];
            app.optim.result.rc2w=[y1(7,:),y2(7,2:end)];
            if (nb_nested>1)
                app.optim.result.Rc2w2=[y1(8,:),y2(8,2:end)];
                app.optim.result.rc2w2=[y1(9,:),y2(9,2:end)];
            end
            if (nb_nested>2)
                app.optim.result.Rc2w3=[y1(10,:),y2(10,2:end)];
                app.optim.result.rc2w3=[y1(11,:),y2(11,2:end)];
            end
            app.optim.result.R=sqrt(app.optim.result.R2w./app.optim.result.w);
            app.optim.result.r=sqrt(app.optim.result.r2w./app.optim.result.w);
            app.optim.result.mu=arrayfun(app.optim.param.viscosity,app.optim.result.T);
            app.optim.result.dwdz=app.optim.result.tau./(3*pi*app.optim.result.mu.*(app.optim.result.R.^2-app.optim.result.r.^2));
            app.optim.result.Rc=sqrt(app.optim.result.Rc2w./app.optim.result.w);
            app.optim.result.rc=sqrt(app.optim.result.rc2w./app.optim.result.w);
            if (nb_nested>1)
                app.optim.result.Rc2=sqrt(app.optim.result.Rc2w2./app.optim.result.w);
                app.optim.result.rc2=sqrt(app.optim.result.rc2w2./app.optim.result.w);
            end
            if (nb_nested>2)
                app.optim.result.Rc3=sqrt(app.optim.result.Rc2w3./app.optim.result.w);
                app.optim.result.rc3=sqrt(app.optim.result.rc2w3./app.optim.result.w);
            end
            app.optim.result.rcontact=app.optim.result.r*sin(pi/app.optim.param.n)/(1+sin(pi/app.optim.param.n));
            if ((abs(app.optim.result.Rc(end)-app.optim.param.Rcf)/app.optim.param.Rcf>0.01) || (abs(app.optim.result.Rc(end)-app.optim.result.rc(end)-app.optim.param.tf)/app.optim.param.tf>0.01) || (abs(app.optim.result.r(end)*2-app.optim.result.Rc(end)*4-app.optim.param.Dcore)/app.optim.param.Dcore>0.01))
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Final paremeters are different from the desired parameters. Check the intervals of the approximate parameters.','Error');
            end
            app.optim_fait=true;
            app.SaveresultsButton_2.Enable='on';
            close(d);
            app.OptimmaxLabel.Interpreter='html';
            if (max(app.optim.result.Rc./app.optim.result.rcontact)>=1)
                app.OptimmaxLabel.Text=strcat('max(Rc/Rcontact) = <font color="red">',num2str(max(app.optim.result.Rc./app.optim.result.rcontact)),'</font>');
            else
                app.OptimmaxLabel.Text=strcat('max(Rc/Rcontact) = <font color="green">',num2str(max(app.optim.result.Rc./app.optim.result.rcontact)),'</font>');
            end
            if (nb_nested>1)
                app.maxDc2dc1Label_2.Interpreter='html';
                if (max(app.optim.result.Rc2./app.optim.result.rc)>=1)
                    app.maxDc2dc1Label_2.Text=strcat('max(Dc2/dc1) = <font color="red">',num2str(max(app.optim.result.Rc2./app.optim.result.rc)),'</font>');
                else
                    app.maxDc2dc1Label_2.Text=strcat('max(Dc2/dc1) = <font color="green">',num2str(max(app.optim.result.Rc2./app.optim.result.rc)),'</font>');
                end
            else
                app.maxDc2dc1Label_2.Text='max(Dc2/dc1) = ';
            end
            if (nb_nested>2)
                app.maxDc3dc2Label_2.Interpreter='html';
                if (max(app.optim.result.Rc3./app.optim.result.rc2)>=1)
                    app.maxDc3dc2Label_2.Text=strcat('max(Dc3/dc2) = <font color="red">',num2str(max(app.optim.result.Rc3./app.optim.result.rc2)),'</font>');
                else
                    app.maxDc3dc2Label_2.Text=strcat('max(Dc3/dc2) = <font color="green">',num2str(max(app.optim.result.Rc3./app.optim.result.rc2)),'</font>');
                end
            else
                app.maxDc3dc2Label_2.Text='max(Dc3/dc2) = ';
            end

            app.GrandeurAReprrsenter_DropDown2.Enable=true;
            app.zmSlider_2.Value=0;
            trace_optim(app);

        end

        % Button pushed function: SaveresultsButton_2
        function SaveresultsButton_2Pushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            result=app.optim.result;
            param=app.optim.param;
            save(strcat(path,file),'result','param');
        end

        % Value changed function: GrandeurAReprrsenter_DropDown2
        function GrandeurAReprrsenter_DropDown2ValueChanged(app, event)
            trace_optim(app);
        end

        % Value changing function: zmSlider_2
        function zmSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            [A,pos]=min(abs(changingValue-app.optim.result.z));
            app.graph2.r0.Position=[-app.optim.result.R(pos)*1E6,-app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6];
            app.graph2.r0bis.Position=[-app.optim.result.r(pos)*1E6,-app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6];
            for j=1:app.optim.param.n
                app.graph2.r0.Position=[-app.optim.result.R(pos)*1E6,-app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6];
                app.graph2.r0bis.Position=[-app.optim.result.r(pos)*1E6,-app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6];
            end
            for j=1:app.optim.param.n
                if (isreal(app.optim.result.Rc(pos))) && (~isnan(app.optim.result.Rc(pos))) && (isreal(app.optim.result.rc(pos))) && (~isnan(app.optim.result.rc(pos)))
                    xcenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                    ycenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                    app.graph2.r(j).Position=[xcenter-app.optim.result.Rc(pos)*1E6,ycenter-app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6];
                    app.graph2.rbis(j).Position=[xcenter-app.optim.result.rc(pos)*1E6,ycenter-app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6];
                    if (app.optim.result.nb_nested>1)
                        if (isreal(app.optim.result.Rc2(pos))) && (~isnan(app.optim.result.Rc2(pos))) && (isreal(app.optim.result.rc2(pos))) && (~isnan(app.optim.result.rc2(pos)))
                            xcenter=(app.optim.result.r(pos)-app.optim.result.Rc2(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                            ycenter=(app.optim.result.r(pos)-app.optim.result.Rc2(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                            app.graph2.r2(j).Position=[xcenter-app.optim.result.Rc2(pos)*1E6,ycenter-app.optim.result.Rc2(pos)*1E6,2*app.optim.result.Rc2(pos)*1E6,2*app.optim.result.Rc2(pos)*1E6];
                            app.graph2.rbis2(j).Position=[xcenter-app.optim.result.rc2(pos)*1E6,ycenter-app.optim.result.rc2(pos)*1E6,2*app.optim.result.rc2(pos)*1E6,2*app.optim.result.rc2(pos)*1E6];
                            if (app.optim.result.nb_nested>2)
                                if (isreal(app.optim.result.Rc3(pos))) && (~isnan(app.optim.result.Rc3(pos))) && (isreal(app.optim.result.rc3(pos))) && (~isnan(app.optim.result.rc3(pos)))
                                    xcenter=(app.optim.result.r(pos)-app.optim.result.Rc3(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos)-app.optim.result.Rc2(pos)+app.optim.result.rc2(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                                    ycenter=(app.optim.result.r(pos)-app.optim.result.Rc3(pos)-app.optim.result.Rc(pos)+app.optim.result.rc(pos)-app.optim.result.Rc2(pos)+app.optim.result.rc2(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                                    app.graph2.r3(j).Position=[xcenter-app.optim.result.Rc3(pos)*1E6,ycenter-app.optim.result.Rc3(pos)*1E6,2*app.optim.result.Rc3(pos)*1E6,2*app.optim.result.Rc3(pos)*1E6];
                                    app.graph2.rbis3(j).Position=[xcenter-app.optim.result.rc3(pos)*1E6,ycenter-app.optim.result.rc3(pos)*1E6,2*app.optim.result.rc3(pos)*1E6,2*app.optim.result.rc3(pos)*1E6];
                                end
                            end
                        end
                    end
                end
            end
            app.UIAxes_2.XLim = app.UIAxes_2.YLim;
        end

        % Value changed function: Tmax_EditField
        function Tmax_EditFieldValueChanged(app, event)
            trace(app);
        end

        % Value changed function: OptimTmaxminEditField
        function OptimTmaxminEditFieldValueChanged(app, event)
            trace_optim(app);
        end

        % Value changed function: OptimTmaxmaxEditField
        function OptimTmaxmaxEditFieldValueChanged(app, event)
            trace_optim(app);
        end

        % Button pushed function: Button, Button_2, Button_3, Button_4, 
        % ...and 1 other component
        function ButtonPushed(app, event)
            web('https://github.com/ovanvincq/HARF-drawing','-browser');
        end

        % Close request function: 
        % TubularantiresonanthollowcorefibersdrawingsimulationUIFigure
        function TubularantiresonanthollowcorefibersdrawingsimulationUIFigureCloseRequest(app, event)
            save_param(app,strcat(userpath,'\param.mat'));
            delete(gcp)
            delete(app)
        end

        % Value changed function: VolumicMass_Jacket_EditField
        function VolumicMass_Jacket_EditFieldValueChanged(app, event)
            value = app.VolumicMass_Jacket_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.VolumicMass_Jacket_EditField.FontColor=[0,0,0];
                app.paramini.rho=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in volumic mass','Input Error');
                app.VolumicMass_Jacket_EditField.FontColor=[1,0,0];
                app.paramini.rho=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: SurfaceTension_Jacket_EditField
        function SurfaceTension_Jacket_EditFieldValueChanged(app, event)
            value = app.SurfaceTension_Jacket_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SurfaceTension_Jacket_EditField.FontColor=[0,0,0];
                app.paramini.gamma=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in surface tension','Input Error');
                app.SurfaceTension_Jacket_EditField.FontColor=[1,0,0];
                app.paramini.gamma=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: SpecificHeat_Jacket_EditField
        function SpecificHeat_Jacket_EditFieldValueChanged(app, event)
            value = app.SpecificHeat_Jacket_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SpecificHeat_Jacket_EditField.FontColor=[0,0,0];
                app.paramini.Cp=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in specific heat','Input Error');
                app.SpecificHeat_Jacket_EditField.FontColor=[1,0,0];
                app.paramini.Cp=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: Viscosity_Jacket_EditField
        function Viscosity_Jacket_EditFieldValueChanged(app, event)
            value = app.Viscosity_Jacket_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Viscosity_Jacket_EditField.FontColor=[0,0,0];
                app.paramini.viscosity=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in viscosity','Input Error');
                app.Viscosity_Jacket_EditField.FontColor=[1,0,0];
                app.paramini.viscosity=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: Emissivity_Jacket_EditField
        function Emissivity_Jacket_EditFieldValueChanged(app, event)
            value = app.Emissivity_Jacket_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Emissivity_Jacket_EditField.FontColor=[0,0,0];
                app.paramini.alpha=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in emissivity','Input Error');
                app.Emissivity_Jacket_EditField.FontColor=[1,0,0];
                app.paramini.alpha=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: GrandeurARepresenter_DropDown4
        function GrandeurARepresenter_DropDown4ValueChanged(app, event)
            trace_parameters(app);
        end

        % Value changed function: Viscosity_Capillaries_EditField
        function Viscosity_Capillaries_EditFieldValueChanged(app, event)
            value = app.Viscosity_Capillaries_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Viscosity_Capillaries_EditField.FontColor=[0,0,0];
                app.paramini.viscosity_1=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in viscosity for capillaries #1','Input Error');
                app.Viscosity_Capillaries_EditField.FontColor=[1,0,0];
                app.paramini.viscosity_1=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
            
        end

        % Value changed function: SurfaceTension_Capillaries_EditField
        function SurfaceTension_Capillaries_EditFieldValueChanged(app, event)
            value = app.SurfaceTension_Capillaries_EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SurfaceTension_Capillaries_EditField.FontColor=[0,0,0];
                app.paramini.gamma_1=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in surface tension for capillaries #1','Input Error');
                app.SurfaceTension_Capillaries_EditField.FontColor=[1,0,0];
                app.paramini.gamma_1=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
            
        end

        % Value changed function: TemperatureProfile_Furnace_EditField
        function TemperatureProfile_Furnace_EditFieldValueChanged(app, event)
            value = app.TemperatureProfile_Furnace_EditField.Value;
            try
                fun=str2func(strcat('@(z)',value));
                ztest=[0,app.Length_Furnace_EditField.Value];
                arrayfun(fun,ztest);
                app.TemperatureProfile_Furnace_EditField.FontColor=[0,0,0];
                app.paramini.furnace_profile=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in the furnace temperature profile','Input Error');
                app.TemperatureProfile_Furnace_EditField.FontColor=[1,0,0];
                app.paramini.furnace_profile=[];
            end
            trace(app);          
            trace_optim(app);
            trace_furnace(app);
        end

        % Value changed function: Length_Furnace_EditField
        function Length_Furnace_EditFieldValueChanged(app, event)
            trace(app);          
            trace_optim(app);
            trace_furnace(app);
        end

        % Value changed function: d0_Jacket_EditField
        function d0_Jacket_EditFieldValueChanged(app, event)
            compute_d0D0(app);
            compute_dc01Dc01(app)
            update_optim_objectifs(app);
        end

        % Value changed function: D0_Jacket_EditField
        function D0_Jacket_EditFieldValueChanged(app, event)
            compute_d0D0(app);
            update_optim_objectifs(app);
        end

        % Value changed function: dc0_Capillaries_EditField
        function dc0_Capillaries_EditFieldValueChanged(app, event)
            compute_dc01Dc01(app);
            compute_dc02Dc02(app);
            update_optim_objectifs(app);
            
        end

        % Value changed function: Dc0_Capillaries_EditField
        function Dc0_Capillaries_EditFieldValueChanged(app, event)
            compute_dc01Dc01(app);
            update_optim_objectifs(app);
            
        end

        % Value changed function: NestedCapillaries_Spinner
        function NestedCapillaries_SpinnerValueChanged(app, event)
            value = app.NestedCapillaries_Spinner.Value;
            if (value == 3)
                set_cap2(app,true);
                set_cap3(app,true);
            elseif(value==2)
                set_cap2(app,true);
                set_cap3(app,false);
            else
                set_cap2(app,false);
                set_cap3(app,false);
            end
            trace_parameters(app);
        end

        % Value changed function: NumberOfCapillaries_Spinner
        function NumberOfCapillaries_SpinnerValueChanged(app, event)
            compute_dc01Dc01(app);
            update_optim_objectifs(app);
        end

        % Value changed function: Dc0_Capillaries_EditField_2
        function Dc0_Capillaries_EditField_2ValueChanged(app, event)
            compute_dc02Dc02(app);
            update_optim_objectifs(app);
        end

        % Value changed function: dc0_Capillaries_EditField_2
        function dc0_Capillaries_EditField_2ValueChanged(app, event)
            compute_dc02Dc02(app);
            compute_dc03Dc03(app);
            update_optim_objectifs(app);
        end

        % Value changed function: Dc0_Capillaries_EditField_3
        function Dc0_Capillaries_EditField_3ValueChanged(app, event)
            compute_dc03Dc03(app);
            update_optim_objectifs(app);
        end

        % Value changed function: dc0_Capillaries_EditField_3
        function dc0_Capillaries_EditField_3ValueChanged(app, event)
            compute_dc03Dc03(app);
            update_optim_objectifs(app);
        end

        % Value changed function: SurfaceTension_Capillaries_EditField_2
        function SurfaceTension_Capillaries_EditField_2ValueChanged(app, event)
            value = app.SurfaceTension_Capillaries_EditField_2.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SurfaceTension_Capillaries_EditField_2.FontColor=[0,0,0];
                app.paramini.gamma_2=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in surface tension for capillaries #2','Input Error');
                app.SurfaceTension_Capillaries_EditField_2.FontColor=[1,0,0];
                app.paramini.gamma_2=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
            
        end

        % Value changed function: SurfaceTension_Capillaries_EditField_3
        function SurfaceTension_Capillaries_EditField_3ValueChanged(app, event)
            value = app.SurfaceTension_Capillaries_EditField_3.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SurfaceTension_Capillaries_EditField_3.FontColor=[0,0,0];
                app.paramini.gamma_3=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in surface tension for capillaries #3','Input Error');
                app.SurfaceTension_Capillaries_EditField_3.FontColor=[1,0,0];
                app.paramini.gamma_3=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
            
        end

        % Value changed function: Viscosity_Capillaries_EditField_2
        function Viscosity_Capillaries_EditField_2ValueChanged(app, event)
            value = app.Viscosity_Capillaries_EditField_2.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Viscosity_Capillaries_EditField.FontColor=[0,0,0];
                app.paramini.viscosity_2=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in viscosity for capillaries #2','Input Error');
                app.Viscosity_Capillaries_EditField.FontColor=[1,0,0];
                app.paramini.viscosity_2=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: Viscosity_Capillaries_EditField_3
        function Viscosity_Capillaries_EditField_3ValueChanged(app, event)
            value = app.Viscosity_Capillaries_EditField_3.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Viscosity_Capillaries_EditField_3.FontColor=[0,0,0];
                app.paramini.viscosity_3=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in viscosity for capillaries #3','Input Error');
                app.Viscosity_Capillaries_EditField_3.FontColor=[1,0,0];
                app.paramini.viscosity_3=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: GrandeurARepresenter_DropDown5
        function GrandeurARepresenter_DropDown5ValueChanged(app, event)
            trace_parameters(app);
        end

        % Value changed function: SimulationLength_EditField
        function SimulationLength_EditFieldValueChanged(app, event)
            trace(app);
            trace_optim(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure = uifigure('Visible', 'off');
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.Name = 'Tubular anti-resonant hollow-core fibers drawing simulation';
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.CloseRequestFcn = createCallbackFcn(app, @TubularantiresonanthollowcorefibersdrawingsimulationUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.UIAxes_3 = uiaxes(app.ParametersTab);
            xlabel(app.UIAxes_3, 'z (m)')
            ylabel(app.UIAxes_3, 'Furnace Temperature (K)')
            app.UIAxes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Image_4 = uiimage(app.ParametersTab);
            app.Image_4.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image_4.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image_4.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.Button_4 = uibutton(app.ParametersTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button_4.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button_4.Text = '';
            app.FurnacePanel = uipanel(app.ParametersTab);
            app.FurnacePanel.Title = 'Furnace';
            app.FurnacetemperatureprofileLabel = uilabel(app.FurnacePanel);
            app.FurnacetemperatureprofileLabel.Tooltip = {'Temperature profile of the furnace (function of z)'};
            app.FurnacetemperatureprofileLabel.Text = 'Temperature profile';
            app.TemperatureProfile_Furnace_EditField = uieditfield(app.FurnacePanel, 'text');
            app.TemperatureProfile_Furnace_EditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureProfile_Furnace_EditFieldValueChanged, true);
            app.TemperatureProfile_Furnace_EditField.Tooltip = {'T/Tmax as a function of z'};
            app.TemperatureProfile_Furnace_EditField.Value = 'exp(-(z-0.04)^2/2/0.06^2)';
            app.LengthmEditFieldLabel = uilabel(app.FurnacePanel);
            app.LengthmEditFieldLabel.Tooltip = {'Length of the furnace'};
            app.LengthmEditFieldLabel.Text = 'Length (m)';
            app.Length_Furnace_EditField = uieditfield(app.FurnacePanel, 'numeric');
            app.Length_Furnace_EditField.Limits = [0 Inf];
            app.Length_Furnace_EditField.ValueChangedFcn = createCallbackFcn(app, @Length_Furnace_EditFieldValueChanged, true);
            app.Length_Furnace_EditField.Value = 0.065;
            app.ConstantsPanel = uipanel(app.ParametersTab);
            app.ConstantsPanel.Title = 'Constants';
            app.StefanBoltzmannconstantWmK4EditFieldLabel = uilabel(app.ConstantsPanel);
            app.StefanBoltzmannconstantWmK4EditFieldLabel.Text = 'Stefan-Boltzmann constant (W/m²/K^4)';
            app.StefanBoltzmannConstant_EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.StefanBoltzmannConstant_EditField.Limits = [0 Inf];
            app.StefanBoltzmannConstant_EditField.Value = 5.67e-08;
            app.NewtonheattransfercoefficientWmKEditFieldLabel = uilabel(app.ConstantsPanel);
            app.NewtonheattransfercoefficientWmKEditFieldLabel.Text = 'Newton heat transfer coefficient (W/m²/K)';
            app.NewtonHeatTransferCoefficient_EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.NewtonHeatTransferCoefficient_EditField.Limits = [0 Inf];
            app.NewtonHeatTransferCoefficient_EditField.Value = 100;
            app.GravitationalaccelerationmsEditFieldLabel = uilabel(app.ConstantsPanel);
            app.GravitationalaccelerationmsEditFieldLabel.Text = 'Gravitational acceleration (m/s²)';
            app.GravitationalAcceleration_EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.GravitationalAcceleration_EditField.Limits = [0 Inf];
            app.GravitationalAcceleration_EditField.Value = 9.81;
            app.Differenceoftemperaturebetweenthefurnaceandthepreformatz0KLabel = uilabel(app.ConstantsPanel);
            app.Differenceoftemperaturebetweenthefurnaceandthepreformatz0KLabel.Text = 'T(furnace)-T(preform) at z=0 (K)';
            app.DeltaT_EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.DeltaT_EditField.Value = 500;
            app.ExternaltemperatureCEditFieldLabel = uilabel(app.ConstantsPanel);
            app.ExternaltemperatureCEditFieldLabel.Text = 'External temperature (°C)';
            app.ExternalTemperature_EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.ExternalTemperature_EditField.Limits = [-273.15 Inf];
            app.ExternalTemperature_EditField.Value = 20;
            app.SimulationlengthmEditFieldLabel = uilabel(app.ConstantsPanel);
            app.SimulationlengthmEditFieldLabel.Tooltip = {'Total length'};
            app.SimulationlengthmEditFieldLabel.Text = 'Simulation length (m)';
            app.SimulationLength_EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.SimulationLength_EditField.Limits = [0.1 Inf];
            app.SimulationLength_EditField.ValueChangedFcn = createCallbackFcn(app, @SimulationLength_EditFieldValueChanged, true);
            app.SimulationLength_EditField.Value = 0.1;
            app.DrawingparametersPanel = uipanel(app.ParametersTab);
            app.DrawingparametersPanel.Title = 'Drawing parameters';
            app.FeedspeedmmminEditField_2Label = uilabel(app.DrawingparametersPanel);
            app.FeedspeedmmminEditField_2Label.Tooltip = {'Feed speed'};
            app.FeedspeedmmminEditField_2Label.Text = 'Feed speed (mm/min)';
            app.FeedSpeed_EditField = uieditfield(app.DrawingparametersPanel, 'numeric');
            app.FeedSpeed_EditField.Limits = [0 Inf];
            app.FeedSpeed_EditField.Value = 4;
            app.DrawtensiongEditField_2Label = uilabel(app.DrawingparametersPanel);
            app.DrawtensiongEditField_2Label.Tooltip = {'Draw tension'};
            app.DrawtensiongEditField_2Label.Text = 'Draw tension (g)';
            app.DrawTension_EditField = uieditfield(app.DrawingparametersPanel, 'numeric');
            app.DrawTension_EditField.Limits = [0 Inf];
            app.DrawTension_EditField.Value = 84;
            app.JacketTab = uitab(app.TabGroup);
            app.JacketTab.Title = 'Jacket';
            app.UIAxes_4 = uiaxes(app.JacketTab);
            xlabel(app.UIAxes_4, 'T (K)')
            ylabel(app.UIAxes_4, 'Volumic mass (kg/m^3)')
            app.UIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MaterialpropertiesFunctionsofTKPanel = uipanel(app.JacketTab);
            app.MaterialpropertiesFunctionsofTKPanel.Title = 'Material properties - Functions of T(K)';
            app.Volumicmasskgm3EditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.Volumicmasskgm3EditFieldLabel.Text = 'Volumic mass (kg/m^3)';
            app.VolumicMass_Jacket_EditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.VolumicMass_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @VolumicMass_Jacket_EditFieldValueChanged, true);
            app.VolumicMass_Jacket_EditField.Value = '2200';
            app.SurfacetensionNmEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.SurfacetensionNmEditFieldLabel.Text = 'Surface tension (N/m)';
            app.SurfaceTension_Jacket_EditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.SurfaceTension_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceTension_Jacket_EditFieldValueChanged, true);
            app.SurfaceTension_Jacket_EditField.Value = '0.3';
            app.SpecificheatJkgKEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.SpecificheatJkgKEditFieldLabel.Text = 'Specific heat (J/kg/K)';
            app.SpecificHeat_Jacket_EditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.SpecificHeat_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @SpecificHeat_Jacket_EditFieldValueChanged, true);
            app.SpecificHeat_Jacket_EditField.Value = '-1.9202379E-11*(9*min(max(310.75,T),1669.67)/5-459.4)^4+8.9608033E-8*(9*min(max(310.75,T),1669.67)/5-459.4)^3-2.0008399E-4*(9*min(max(310.75,T),1669.67)/5-459.4)^2+0.59235423*(9*min(max(310.75,T),1669.67)/5-459.4)+6.2175046E2';
            app.ViscosityPasEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.ViscosityPasEditFieldLabel.Text = 'Viscosity (Pa.s)';
            app.Viscosity_Jacket_EditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.Viscosity_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @Viscosity_Jacket_EditFieldValueChanged, true);
            app.Viscosity_Jacket_EditField.Value = '10^(-7.946)*exp(66337/T)';
            app.EmissivityEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.EmissivityEditFieldLabel.Text = 'Emissivity ';
            app.Emissivity_Jacket_EditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.Emissivity_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @Emissivity_Jacket_EditFieldValueChanged, true);
            app.Emissivity_Jacket_EditField.Value = '0.885';
            app.GrandeurARepresenter_DropDown4 = uidropdown(app.JacketTab);
            app.GrandeurARepresenter_DropDown4.Items = {'Volumic mass', 'Surface tension', 'Specific heat', 'Viscosity', 'Emissivity'};
            app.GrandeurARepresenter_DropDown4.ValueChangedFcn = createCallbackFcn(app, @GrandeurARepresenter_DropDown4ValueChanged, true);
            app.GrandeurARepresenter_DropDown4.Value = 'Volumic mass';
            app.Image_3 = uiimage(app.JacketTab);
            app.Image_3.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image_3.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image_3.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.Button_3 = uibutton(app.JacketTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button_3.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button_3.Text = '';
            app.GeometricparametersPanel = uipanel(app.JacketTab);
            app.GeometricparametersPanel.Title = 'Geometric parameters';
            app.D0mmEditField_2Label_2 = uilabel(app.GeometricparametersPanel);
            app.D0mmEditField_2Label_2.Tooltip = {'Initial outer diameter of the jacket'};
            app.D0mmEditField_2Label_2.Text = 'D0 (mm)';
            app.D0_Jacket_EditField = uieditfield(app.GeometricparametersPanel, 'numeric');
            app.D0_Jacket_EditField.Limits = [0 Inf];
            app.D0_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @D0_Jacket_EditFieldValueChanged, true);
            app.D0_Jacket_EditField.Value = 8.3556;
            app.d0mmEditField_2Label = uilabel(app.GeometricparametersPanel);
            app.d0mmEditField_2Label.Tooltip = {'Initial inner diameter of the jacket'};
            app.d0mmEditField_2Label.Text = 'd0 (mm)';
            app.d0_Jacket_EditField = uieditfield(app.GeometricparametersPanel, 'numeric');
            app.d0_Jacket_EditField.Limits = [0 Inf];
            app.d0_Jacket_EditField.ValueChangedFcn = createCallbackFcn(app, @d0_Jacket_EditFieldValueChanged, true);
            app.d0_Jacket_EditField.Value = 2.4;
            app.d0D0Label = uilabel(app.GeometricparametersPanel);
            app.d0D0Label.Text = 'd0/D0 = ';
            app.CapillariesTab = uitab(app.TabGroup);
            app.CapillariesTab.Title = 'Capillaries';
            app.UIAxes_5 = uiaxes(app.CapillariesTab);
            xlabel(app.UIAxes_5, 'T (K)')
            ylabel(app.UIAxes_5, 'Volumic mass (kg/m^3)')
            app.UIAxes_5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MaterialpropertiesFunctionsofTCapillaries1Panel = uipanel(app.CapillariesTab);
            app.MaterialpropertiesFunctionsofTCapillaries1Panel.Title = 'Material properties - Functions of T - Capillaries #1';
            app.SurfacetensionNmEditField_2Label = uilabel(app.MaterialpropertiesFunctionsofTCapillaries1Panel);
            app.SurfacetensionNmEditField_2Label.Text = 'Surface tension (N/m)';
            app.SurfaceTension_Capillaries_EditField = uieditfield(app.MaterialpropertiesFunctionsofTCapillaries1Panel, 'text');
            app.SurfaceTension_Capillaries_EditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceTension_Capillaries_EditFieldValueChanged, true);
            app.SurfaceTension_Capillaries_EditField.Value = '0.3';
            app.ViscosityPasEditField_2Label = uilabel(app.MaterialpropertiesFunctionsofTCapillaries1Panel);
            app.ViscosityPasEditField_2Label.Text = 'Viscosity (Pa.s)';
            app.Viscosity_Capillaries_EditField = uieditfield(app.MaterialpropertiesFunctionsofTCapillaries1Panel, 'text');
            app.Viscosity_Capillaries_EditField.ValueChangedFcn = createCallbackFcn(app, @Viscosity_Capillaries_EditFieldValueChanged, true);
            app.Viscosity_Capillaries_EditField.Value = '10^(-7.946)*exp(66337/T)';
            app.MicrostruturedcladdinggeometryPanel = uipanel(app.CapillariesTab);
            app.MicrostruturedcladdinggeometryPanel.Title = 'Microstrutured cladding geometry';
            app.NumberofcapillariesSpinnerLabel = uilabel(app.MicrostruturedcladdinggeometryPanel);
            app.NumberofcapillariesSpinnerLabel.Text = 'Number of capillaries';
            app.NumberOfCapillaries_Spinner = uispinner(app.MicrostruturedcladdinggeometryPanel);
            app.NumberOfCapillaries_Spinner.Limits = [2 Inf];
            app.NumberOfCapillaries_Spinner.ValueChangedFcn = createCallbackFcn(app, @NumberOfCapillaries_SpinnerValueChanged, true);
            app.NumberOfCapillaries_Spinner.Value = 2;
            app.NestedCapillariesSpinnerLabel = uilabel(app.MicrostruturedcladdinggeometryPanel);
            app.NestedCapillariesSpinnerLabel.Text = 'Nested Capillaries';
            app.NestedCapillaries_Spinner = uispinner(app.MicrostruturedcladdinggeometryPanel);
            app.NestedCapillaries_Spinner.Limits = [1 3];
            app.NestedCapillaries_Spinner.ValueChangedFcn = createCallbackFcn(app, @NestedCapillaries_SpinnerValueChanged, true);
            app.NestedCapillaries_Spinner.Value = 1;
            app.Button_5 = uibutton(app.CapillariesTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button_5.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button_5.Text = '';
            app.GeometricparametersPanel_2 = uipanel(app.CapillariesTab);
            app.GeometricparametersPanel_2.Title = 'Geometric parameters';
            app.Dc01mmLabel = uilabel(app.GeometricparametersPanel_2);
            app.Dc01mmLabel.Tooltip = {'Initial outer diameter of the capillary tubes'};
            app.Dc01mmLabel.Text = 'Dc01 (mm)';
            app.Dc0_Capillaries_EditField = uieditfield(app.GeometricparametersPanel_2, 'numeric');
            app.Dc0_Capillaries_EditField.Limits = [0 Inf];
            app.Dc0_Capillaries_EditField.ValueChangedFcn = createCallbackFcn(app, @Dc0_Capillaries_EditFieldValueChanged, true);
            app.Dc0_Capillaries_EditField.Value = 0.48;
            app.dc01mmLabel = uilabel(app.GeometricparametersPanel_2);
            app.dc01mmLabel.Tooltip = {'Initial inner diameter of the capillary tubes'};
            app.dc01mmLabel.Text = 'dc01 (mm)';
            app.dc0_Capillaries_EditField = uieditfield(app.GeometricparametersPanel_2, 'numeric');
            app.dc0_Capillaries_EditField.Limits = [0 Inf];
            app.dc0_Capillaries_EditField.ValueChangedFcn = createCallbackFcn(app, @dc0_Capillaries_EditFieldValueChanged, true);
            app.dc0_Capillaries_EditField.Value = 0.3936;
            app.dc01Dc01Label = uilabel(app.GeometricparametersPanel_2);
            app.dc01Dc01Label.Text = 'dc01/Dc01 = ';
            app.dc02Dc02Label = uilabel(app.GeometricparametersPanel_2);
            app.dc02Dc02Label.Enable = 'off';
            app.dc02Dc02Label.Text = 'dc02/Dc02 = ';
            app.Dc01mmLabel_2 = uilabel(app.GeometricparametersPanel_2);
            app.Dc01mmLabel_2.Enable = 'off';
            app.Dc01mmLabel_2.Tooltip = {'Initial outer diameter of the capillary tubes'};
            app.Dc01mmLabel_2.Text = 'Dc02 (mm)';
            app.Dc0_Capillaries_EditField_2 = uieditfield(app.GeometricparametersPanel_2, 'numeric');
            app.Dc0_Capillaries_EditField_2.Limits = [0 Inf];
            app.Dc0_Capillaries_EditField_2.ValueChangedFcn = createCallbackFcn(app, @Dc0_Capillaries_EditField_2ValueChanged, true);
            app.Dc0_Capillaries_EditField_2.Enable = 'off';
            app.Dc0_Capillaries_EditField_2.Value = 0.48;
            app.dc01mmLabel_2 = uilabel(app.GeometricparametersPanel_2);
            app.dc01mmLabel_2.Enable = 'off';
            app.dc01mmLabel_2.Tooltip = {'Initial inner diameter of the capillary tubes'};
            app.dc01mmLabel_2.Text = 'dc02 (mm)';
            app.dc0_Capillaries_EditField_2 = uieditfield(app.GeometricparametersPanel_2, 'numeric');
            app.dc0_Capillaries_EditField_2.Limits = [0 Inf];
            app.dc0_Capillaries_EditField_2.ValueChangedFcn = createCallbackFcn(app, @dc0_Capillaries_EditField_2ValueChanged, true);
            app.dc0_Capillaries_EditField_2.Enable = 'off';
            app.dc0_Capillaries_EditField_2.Value = 0.3936;
            app.dc03Dc03Label = uilabel(app.GeometricparametersPanel_2);
            app.dc03Dc03Label.Enable = 'off';
            app.dc03Dc03Label.Text = 'dc03/Dc03 = ';
            app.Dc01mmLabel_3 = uilabel(app.GeometricparametersPanel_2);
            app.Dc01mmLabel_3.Enable = 'off';
            app.Dc01mmLabel_3.Tooltip = {'Initial outer diameter of the capillary tubes'};
            app.Dc01mmLabel_3.Text = 'Dc03 (mm)';
            app.Dc0_Capillaries_EditField_3 = uieditfield(app.GeometricparametersPanel_2, 'numeric');
            app.Dc0_Capillaries_EditField_3.Limits = [0 Inf];
            app.Dc0_Capillaries_EditField_3.ValueChangedFcn = createCallbackFcn(app, @Dc0_Capillaries_EditField_3ValueChanged, true);
            app.Dc0_Capillaries_EditField_3.Enable = 'off';
            app.Dc0_Capillaries_EditField_3.Value = 0.48;
            app.dc01mmLabel_3 = uilabel(app.GeometricparametersPanel_2);
            app.dc01mmLabel_3.Enable = 'off';
            app.dc01mmLabel_3.Tooltip = {'Initial inner diameter of the capillary tubes'};
            app.dc01mmLabel_3.Text = 'dc03 (mm)';
            app.dc0_Capillaries_EditField_3 = uieditfield(app.GeometricparametersPanel_2, 'numeric');
            app.dc0_Capillaries_EditField_3.Limits = [0 Inf];
            app.dc0_Capillaries_EditField_3.ValueChangedFcn = createCallbackFcn(app, @dc0_Capillaries_EditField_3ValueChanged, true);
            app.dc0_Capillaries_EditField_3.Enable = 'off';
            app.dc0_Capillaries_EditField_3.Value = 0.3936;
            app.Rc01RcontactLabel = uilabel(app.GeometricparametersPanel_2);
            app.Rc01RcontactLabel.Text = 'Rc01/Rcontact = ';
            app.Dc02dc01Label = uilabel(app.GeometricparametersPanel_2);
            app.Dc02dc01Label.Enable = 'off';
            app.Dc02dc01Label.Text = 'Dc02/dc01 =';
            app.Dc03dc02Label = uilabel(app.GeometricparametersPanel_2);
            app.Dc03dc02Label.Enable = 'off';
            app.Dc03dc02Label.Text = 'Dc03/dc02 = ';
            app.GrandeurARepresenter_DropDown5 = uidropdown(app.CapillariesTab);
            app.GrandeurARepresenter_DropDown5.Items = {'Surface tension', 'Viscosity'};
            app.GrandeurARepresenter_DropDown5.ValueChangedFcn = createCallbackFcn(app, @GrandeurARepresenter_DropDown5ValueChanged, true);
            app.GrandeurARepresenter_DropDown5.Value = 'Surface tension';
            app.Image_5 = uiimage(app.CapillariesTab);
            app.Image_5.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image_5.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image_5.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.MaterialpropertiesFunctionsofTCapillaries2Panel = uipanel(app.CapillariesTab);
            app.MaterialpropertiesFunctionsofTCapillaries2Panel.Enable = 'off';
            app.MaterialpropertiesFunctionsofTCapillaries2Panel.Title = 'Material properties - Functions of T - Capillaries #2';
            app.SurfacetensionNmEditField_2Label_2 = uilabel(app.MaterialpropertiesFunctionsofTCapillaries2Panel);
            app.SurfacetensionNmEditField_2Label_2.Enable = 'off';
            app.SurfacetensionNmEditField_2Label_2.Text = 'Surface tension (N/m)';
            app.SurfaceTension_Capillaries_EditField_2 = uieditfield(app.MaterialpropertiesFunctionsofTCapillaries2Panel, 'text');
            app.SurfaceTension_Capillaries_EditField_2.ValueChangedFcn = createCallbackFcn(app, @SurfaceTension_Capillaries_EditField_2ValueChanged, true);
            app.SurfaceTension_Capillaries_EditField_2.Enable = 'off';
            app.SurfaceTension_Capillaries_EditField_2.Value = '0.3';
            app.ViscosityPasEditField_2Label_2 = uilabel(app.MaterialpropertiesFunctionsofTCapillaries2Panel);
            app.ViscosityPasEditField_2Label_2.Enable = 'off';
            app.ViscosityPasEditField_2Label_2.Text = 'Viscosity (Pa.s)';
            app.Viscosity_Capillaries_EditField_2 = uieditfield(app.MaterialpropertiesFunctionsofTCapillaries2Panel, 'text');
            app.Viscosity_Capillaries_EditField_2.ValueChangedFcn = createCallbackFcn(app, @Viscosity_Capillaries_EditField_2ValueChanged, true);
            app.Viscosity_Capillaries_EditField_2.Enable = 'off';
            app.Viscosity_Capillaries_EditField_2.Value = '10^(-7.946)*exp(66337/T)';
            app.MaterialpropertiesFunctionsofTCapillaries3Panel = uipanel(app.CapillariesTab);
            app.MaterialpropertiesFunctionsofTCapillaries3Panel.Enable = 'off';
            app.MaterialpropertiesFunctionsofTCapillaries3Panel.Title = 'Material properties - Functions of T - Capillaries #3';
            app.SurfacetensionNmEditField_2Label_3 = uilabel(app.MaterialpropertiesFunctionsofTCapillaries3Panel);
            app.SurfacetensionNmEditField_2Label_3.Enable = 'off';
            app.SurfacetensionNmEditField_2Label_3.Text = 'Surface tension (N/m)';
            app.SurfaceTension_Capillaries_EditField_3 = uieditfield(app.MaterialpropertiesFunctionsofTCapillaries3Panel, 'text');
            app.SurfaceTension_Capillaries_EditField_3.ValueChangedFcn = createCallbackFcn(app, @SurfaceTension_Capillaries_EditField_3ValueChanged, true);
            app.SurfaceTension_Capillaries_EditField_3.Enable = 'off';
            app.SurfaceTension_Capillaries_EditField_3.Value = '0.3';
            app.ViscosityPasEditField_2Label_3 = uilabel(app.MaterialpropertiesFunctionsofTCapillaries3Panel);
            app.ViscosityPasEditField_2Label_3.Enable = 'off';
            app.ViscosityPasEditField_2Label_3.Text = 'Viscosity (Pa.s)';
            app.Viscosity_Capillaries_EditField_3 = uieditfield(app.MaterialpropertiesFunctionsofTCapillaries3Panel, 'text');
            app.Viscosity_Capillaries_EditField_3.ValueChangedFcn = createCallbackFcn(app, @Viscosity_Capillaries_EditField_3ValueChanged, true);
            app.Viscosity_Capillaries_EditField_3.Enable = 'off';
            app.Viscosity_Capillaries_EditField_3.Value = '10^(-7.946)*exp(66337/T)';
            app.DirectcomputationTab = uitab(app.TabGroup);
            app.DirectcomputationTab.Title = 'Direct computation';
            app.UIAxes = uiaxes(app.DirectcomputationTab);
            xlabel(app.UIAxes, 'z (m)')
            ylabel(app.UIAxes, 'V (m/min)')
            app.GrandeurARepresenter_DropDown1 = uidropdown(app.DirectcomputationTab);
            app.GrandeurARepresenter_DropDown1.Items = {'V', 'D', 'd', 'Dc', 'dc', 't', 'Dcore', 'dc/Dc', 'Temperature', 'Draw tension', 'Rc1/Rcontact', 'Schematic'};
            app.GrandeurARepresenter_DropDown1.ValueChangedFcn = createCallbackFcn(app, @GrandeurARepresenter_DropDown1ValueChanged, true);
            app.GrandeurARepresenter_DropDown1.Enable = 'off';
            app.GrandeurARepresenter_DropDown1.Value = 'V';
            app.RunButton = uibutton(app.DirectcomputationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.zmSliderLabel = uilabel(app.DirectcomputationTab);
            app.zmSliderLabel.Visible = 'off';
            app.zmSliderLabel.Text = {'z (m)'; ''};
            app.zmSlider = uislider(app.DirectcomputationTab);
            app.zmSlider.Limits = [0 1];
            app.zmSlider.ValueChangingFcn = createCallbackFcn(app, @zmSliderValueChanging, true);
            app.zmSlider.Visible = 'off';
            app.Image = uiimage(app.DirectcomputationTab);
            app.Image.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.Button = uibutton(app.DirectcomputationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button.Text = '';
            app.InputPanel_2 = uipanel(app.DirectcomputationTab);
            app.InputPanel_2.Title = 'Input';
            app.PcoremBarEditFieldLabel = uilabel(app.InputPanel_2);
            app.PcoremBarEditFieldLabel.Tooltip = {'Pressure applied in the core'};
            app.PcoremBarEditFieldLabel.Text = 'Pcore (mBar)';
            app.Pcore_EditField = uieditfield(app.InputPanel_2, 'numeric');
            app.Pcore_EditField.Tooltip = {''};
            app.Pcore_EditField.Value = 15.6;
            app.TmaxCEditFieldLabel = uilabel(app.InputPanel_2);
            app.TmaxCEditFieldLabel.Tooltip = {'Maximum temperature of the furnace'};
            app.TmaxCEditFieldLabel.Text = 'Tmax (°C)';
            app.Tmax_EditField = uieditfield(app.InputPanel_2, 'numeric');
            app.Tmax_EditField.Limits = [1000 Inf];
            app.Tmax_EditField.ValueDisplayFormat = '%.4f';
            app.Tmax_EditField.ValueChangedFcn = createCallbackFcn(app, @Tmax_EditFieldValueChanged, true);
            app.Tmax_EditField.Value = 1806.9;
            app.PcapmbarLabel = uilabel(app.InputPanel_2);
            app.PcapmbarLabel.Tooltip = {'Pressure of the capillary tubes'};
            app.PcapmbarLabel.Text = 'Pcap1 (mbar)';
            app.DeltaP1_EditField = uieditfield(app.InputPanel_2, 'numeric');
            app.DeltaP1_EditField.Value = 63.4;
            app.PcapmbarLabel_2 = uilabel(app.InputPanel_2);
            app.PcapmbarLabel_2.Enable = 'off';
            app.PcapmbarLabel_2.Tooltip = {'Pressure of the capillary tubes'};
            app.PcapmbarLabel_2.Text = 'Pcap2 (mbar)';
            app.DeltaP2_EditField = uieditfield(app.InputPanel_2, 'numeric');
            app.DeltaP2_EditField.Enable = 'off';
            app.DeltaP2_EditField.Value = 63.4;
            app.PcapmbarLabel_3 = uilabel(app.InputPanel_2);
            app.PcapmbarLabel_3.Enable = 'off';
            app.PcapmbarLabel_3.Tooltip = {'Pressure of the capillary tubes'};
            app.PcapmbarLabel_3.Text = 'Pcap3 (mbar)';
            app.DeltaP3_EditField = uieditfield(app.InputPanel_2, 'numeric');
            app.DeltaP3_EditField.Enable = 'off';
            app.DeltaP3_EditField.Value = 63.4;
            app.OutputPanel_2 = uipanel(app.DirectcomputationTab);
            app.OutputPanel_2.Title = 'Output';
            app.DfLabel = uilabel(app.OutputPanel_2);
            app.DfLabel.Tooltip = {'Final outer diameter of the jacket'};
            app.DfLabel.Text = 'Df =';
            app.dfLabel = uilabel(app.OutputPanel_2);
            app.dfLabel.Tooltip = {'Final inner diameter of the jacket'};
            app.dfLabel.Text = 'df =';
            app.Dcf1Label = uilabel(app.OutputPanel_2);
            app.Dcf1Label.Tooltip = {'Final outer diameter of the capillary tubes'};
            app.Dcf1Label.Text = 'Dcf1 = ';
            app.dcf1Label = uilabel(app.OutputPanel_2);
            app.dcf1Label.Tooltip = {'Final inner diameter of the capillary tubes'};
            app.dcf1Label.Text = 'dcf1 =';
            app.tf1Label = uilabel(app.OutputPanel_2);
            app.tf1Label.Tooltip = {'Final thickness of capillary tubes'};
            app.tf1Label.Text = 'tf1 = ';
            app.dcf1Dcf1Label_2 = uilabel(app.OutputPanel_2);
            app.dcf1Dcf1Label_2.Text = 'dcf1/Dcf1 =';
            app.SaveresultsButton = uibutton(app.OutputPanel_2, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Enable = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.Rcf1RcontactLabel = uilabel(app.OutputPanel_2);
            app.Rcf1RcontactLabel.Text = 'Rcf1/Rcontact =';
            app.dcf2Dcf2Label_2 = uilabel(app.OutputPanel_2);
            app.dcf2Dcf2Label_2.Enable = 'off';
            app.dcf2Dcf2Label_2.Text = 'dcf2/Dcf2 =';
            app.dcf3Dcf3Label_2 = uilabel(app.OutputPanel_2);
            app.dcf3Dcf3Label_2.Enable = 'off';
            app.dcf3Dcf3Label_2.Text = 'dcf3/Dcf3 =';
            app.VfLabel = uilabel(app.OutputPanel_2);
            app.VfLabel.Tooltip = {'Draw speed'};
            app.VfLabel.Text = 'Vf = ';
            app.DcoreLabel = uilabel(app.OutputPanel_2);
            app.DcoreLabel.Tooltip = {'Final core diameter'};
            app.DcoreLabel.Text = 'Dcore =';
            app.Dcf2Label = uilabel(app.OutputPanel_2);
            app.Dcf2Label.Enable = 'off';
            app.Dcf2Label.Tooltip = {'Final outer diameter of the capillary tubes'};
            app.Dcf2Label.Text = 'Dcf2 = ';
            app.dcf2Label = uilabel(app.OutputPanel_2);
            app.dcf2Label.Enable = 'off';
            app.dcf2Label.Tooltip = {'Final inner diameter of the capillary tubes'};
            app.dcf2Label.Text = 'dcf2 =';
            app.tf2Label = uilabel(app.OutputPanel_2);
            app.tf2Label.Enable = 'off';
            app.tf2Label.Tooltip = {'Final thickness of capillary tubes'};
            app.tf2Label.Text = 'tf2 = ';
            app.Dcf3Label = uilabel(app.OutputPanel_2);
            app.Dcf3Label.Enable = 'off';
            app.Dcf3Label.Tooltip = {'Final outer diameter of the capillary tubes'};
            app.Dcf3Label.Text = 'Dcf3 = ';
            app.dcf3Label = uilabel(app.OutputPanel_2);
            app.dcf3Label.Enable = 'off';
            app.dcf3Label.Tooltip = {'Final inner diameter of the capillary tubes'};
            app.dcf3Label.Text = 'dcf3 =';
            app.tf3Label = uilabel(app.OutputPanel_2);
            app.tf3Label.Enable = 'off';
            app.tf3Label.Tooltip = {'Final thickness of capillary tubes'};
            app.tf3Label.Text = 'tf3 = ';
            app.OutputPanel_3 = uipanel(app.DirectcomputationTab);
            app.OutputPanel_3.Title = 'Output';
            app.maxRc1RcontactLabel = uilabel(app.OutputPanel_3);
            app.maxRc1RcontactLabel.Text = 'max(Rc1/Rcontact) =';
            app.maxDc2dc1Label = uilabel(app.OutputPanel_3);
            app.maxDc2dc1Label.Enable = 'off';
            app.maxDc2dc1Label.Text = 'max(Dc2/dc1) =';
            app.maxDc3dc2Label = uilabel(app.OutputPanel_3);
            app.maxDc3dc2Label.Enable = 'off';
            app.maxDc3dc2Label.Text = 'max(Dc3/dc2) =';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.UIAxes_2 = uiaxes(app.OptimizationTab);
            xlabel(app.UIAxes_2, 'z (m)')
            ylabel(app.UIAxes_2, 'V (m/min)')
            app.ObjectivePanel = uipanel(app.OptimizationTab);
            app.ObjectivePanel.Title = 'Objective';
            app.tfmLabel = uilabel(app.ObjectivePanel);
            app.tfmLabel.Tooltip = {'Final thickness of the capillary tubes'};
            app.tfmLabel.Text = 'tf (µm)';
            app.OptimtfEditField = uieditfield(app.ObjectivePanel, 'numeric');
            app.OptimtfEditField.Limits = [0 Inf];
            app.OptimtfEditField.ValueChangedFcn = createCallbackFcn(app, @OptimtfEditFieldValueChanged, true);
            app.OptimtfEditField.Value = 3.5;
            app.DcfmLabel = uilabel(app.ObjectivePanel);
            app.DcfmLabel.Tooltip = {'Final outer diameter of the capillary tubes'};
            app.DcfmLabel.Text = 'Dcf1 (µm)';
            app.OptimDcfEditField = uieditfield(app.ObjectivePanel, 'numeric');
            app.OptimDcfEditField.ValueChangedFcn = createCallbackFcn(app, @OptimDcfEditFieldValueChanged, true);
            app.OptimDcfEditField.Value = 110;
            app.DcoremEditFieldLabel = uilabel(app.ObjectivePanel);
            app.DcoremEditFieldLabel.Tooltip = {'Final core diameter'};
            app.DcoremEditFieldLabel.Text = 'Dcore (µm)';
            app.OptimDcoreEditField = uieditfield(app.ObjectivePanel, 'numeric');
            app.OptimDcoreEditField.Limits = [0 Inf];
            app.OptimDcoreEditField.ValueChangedFcn = createCallbackFcn(app, @OptimDcoreEditFieldValueChanged, true);
            app.OptimDcoreEditField.Value = 156;
            app.OptimDfLabel = uilabel(app.ObjectivePanel);
            app.OptimDfLabel.Tooltip = {'Final outer diameter of the jacket'};
            app.OptimDfLabel.Text = 'Df =';
            app.OptimdfLabel = uilabel(app.ObjectivePanel);
            app.OptimdfLabel.Tooltip = {'Final inner diameter of the jacket'};
            app.OptimdfLabel.Text = 'df/Df =';
            app.dcf1Dcf1Label = uilabel(app.ObjectivePanel);
            app.dcf1Dcf1Label.Text = 'dcf1/Dcf1 =';
            app.dcf2Dcf2Label = uilabel(app.ObjectivePanel);
            app.dcf2Dcf2Label.Enable = 'off';
            app.dcf2Dcf2Label.Text = 'dcf2/Dcf2 =';
            app.dcf3Dcf3Label = uilabel(app.ObjectivePanel);
            app.dcf3Dcf3Label.Enable = 'off';
            app.dcf3Dcf3Label.Text = 'dcf3/Dcf3 =';
            app.OptimVfLabel = uilabel(app.ObjectivePanel);
            app.OptimVfLabel.Tooltip = {'Draw speed'};
            app.OptimVfLabel.Text = 'Vf = ';
            app.OptimRcf1Rcontact = uilabel(app.ObjectivePanel);
            app.OptimRcf1Rcontact.Tooltip = {'Draw speed'};
            app.OptimRcf1Rcontact.Text = 'Rcf1/Rcontact =';
            app.Dcf2Label_2 = uilabel(app.ObjectivePanel);
            app.Dcf2Label_2.Text = 'Dcf2 = ';
            app.Dcf3Label_2 = uilabel(app.ObjectivePanel);
            app.Dcf3Label_2.Text = 'Dcf3 = ';
            app.Dcf2dcf1Label = uilabel(app.ObjectivePanel);
            app.Dcf2dcf1Label.Text = 'Dcf2/dcf1 = ';
            app.Dcf3dcf2Label = uilabel(app.ObjectivePanel);
            app.Dcf3dcf2Label.Text = 'Dcf3/dcf2 = ';
            app.zmSlider_2Label = uilabel(app.OptimizationTab);
            app.zmSlider_2Label.Visible = 'off';
            app.zmSlider_2Label.Text = {'z (m)'; ''};
            app.zmSlider_2 = uislider(app.OptimizationTab);
            app.zmSlider_2.Limits = [0 1];
            app.zmSlider_2.ValueChangingFcn = createCallbackFcn(app, @zmSlider_2ValueChanging, true);
            app.zmSlider_2.Visible = 'off';
            app.GrandeurAReprrsenter_DropDown2 = uidropdown(app.OptimizationTab);
            app.GrandeurAReprrsenter_DropDown2.Items = {'V', 'D', 'd', 'Dc', 'dc', 't', 'Dcore', 'dc/Dc', 'Temperature', 'Draw tension', 'Rc1/Rcontact', 'Schematic'};
            app.GrandeurAReprrsenter_DropDown2.ValueChangedFcn = createCallbackFcn(app, @GrandeurAReprrsenter_DropDown2ValueChanged, true);
            app.GrandeurAReprrsenter_DropDown2.Enable = 'off';
            app.GrandeurAReprrsenter_DropDown2.Value = 'V';
            app.OutputPanel = uipanel(app.OptimizationTab);
            app.OutputPanel.Title = 'Output';
            app.OptimmaxLabel = uilabel(app.OutputPanel);
            app.OptimmaxLabel.Text = 'max(Rc1/Rcontact) = ';
            app.OptimdpLabel = uilabel(app.OutputPanel);
            app.OptimdpLabel.Text = 'Pcap1 =';
            app.SaveresultsButton_2 = uibutton(app.OutputPanel, 'push');
            app.SaveresultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButton_2Pushed, true);
            app.SaveresultsButton_2.Enable = 'off';
            app.SaveresultsButton_2.Text = 'Save results';
            app.maxDc2dc1Label_2 = uilabel(app.OutputPanel);
            app.maxDc2dc1Label_2.Text = 'max(Dc2/dc1) =';
            app.maxDc3dc2Label_2 = uilabel(app.OutputPanel);
            app.maxDc3dc2Label_2.Text = 'max(Dc3/dc2) =';
            app.OptimdpLabel_2 = uilabel(app.OutputPanel);
            app.OptimdpLabel_2.Text = 'Pcap2 =';
            app.OptimdpLabel_3 = uilabel(app.OutputPanel);
            app.OptimdpLabel_3.Text = 'Pcap3 =';
            app.OptimPcoreLabel = uilabel(app.OutputPanel);
            app.OptimPcoreLabel.Text = 'Pcore = ';
            app.OptimTmaxLabel = uilabel(app.OutputPanel);
            app.OptimTmaxLabel.Text = 'Tmax = ';
            app.Image_2 = uiimage(app.OptimizationTab);
            app.Image_2.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image_2.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image_2.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.ApproximatevaluesPanel = uipanel(app.OptimizationTab);
            app.ApproximatevaluesPanel.Title = 'Approximate values';
            app.TmaxCLabel = uilabel(app.ApproximatevaluesPanel);
            app.TmaxCLabel.Tooltip = {'Maximum temperature of the furnace'};
            app.TmaxCLabel.Text = 'Tmax(°C)';
            app.minEditFieldLabel = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel.Text = 'min';
            app.OptimTmaxminEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimTmaxminEditField.Limits = [1500 2500];
            app.OptimTmaxminEditField.ValueChangedFcn = createCallbackFcn(app, @OptimTmaxminEditFieldValueChanged, true);
            app.OptimTmaxminEditField.Value = 1750;
            app.maxEditFieldLabel = uilabel(app.ApproximatevaluesPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.OptimTmaxmaxEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimTmaxmaxEditField.Limits = [1500 2500];
            app.OptimTmaxmaxEditField.ValueChangedFcn = createCallbackFcn(app, @OptimTmaxmaxEditFieldValueChanged, true);
            app.OptimTmaxmaxEditField.Value = 1850;
            app.PcorembarLabel = uilabel(app.ApproximatevaluesPanel);
            app.PcorembarLabel.Tooltip = {'Pressure applied in the core'};
            app.PcorembarLabel.Text = 'Pcore (mbar)';
            app.minEditFieldLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel_2.Text = 'min';
            app.OptimPcoreminEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimPcoreminEditField.Value = 10;
            app.maxEditFieldLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.maxEditFieldLabel_2.Text = 'max';
            app.OptimPcoremaxEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimPcoremaxEditField.Value = 20;
            app.minEditFieldLabel_3 = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel_3.Text = 'min';
            app.OptimDeltaPminEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPminEditField.Value = 50;
            app.maxLabel = uilabel(app.ApproximatevaluesPanel);
            app.maxLabel.Text = 'max';
            app.OptimDeltaPmaxEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPmaxEditField.Value = 150;
            app.DeltaPmbarLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.DeltaPmbarLabel_2.Tooltip = {'Pressure of the capillary tubes'};
            app.DeltaPmbarLabel_2.Text = 'Pcap1 (mbar)';
            app.DeltaPmbarLabel_3 = uilabel(app.ApproximatevaluesPanel);
            app.DeltaPmbarLabel_3.Enable = 'off';
            app.DeltaPmbarLabel_3.Tooltip = {'Pressure of the capillary tubes'};
            app.DeltaPmbarLabel_3.Text = 'Pcap2 (mbar)';
            app.minEditFieldLabel_4 = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel_4.Enable = 'off';
            app.minEditFieldLabel_4.Text = 'min';
            app.OptimDeltaPminEditField_2 = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPminEditField_2.Enable = 'off';
            app.OptimDeltaPminEditField_2.Value = 50;
            app.maxLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.maxLabel_2.Enable = 'off';
            app.maxLabel_2.Text = 'max';
            app.OptimDeltaPmaxEditField_2 = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPmaxEditField_2.Enable = 'off';
            app.OptimDeltaPmaxEditField_2.Value = 150;
            app.DeltaPmbarLabel_4 = uilabel(app.ApproximatevaluesPanel);
            app.DeltaPmbarLabel_4.Enable = 'off';
            app.DeltaPmbarLabel_4.Tooltip = {'Pressure of the capillary tubes'};
            app.DeltaPmbarLabel_4.Text = 'Pcap3 (mbar)';
            app.minEditFieldLabel_5 = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel_5.Enable = 'off';
            app.minEditFieldLabel_5.Text = 'min';
            app.OptimDeltaPminEditField_3 = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPminEditField_3.Enable = 'off';
            app.OptimDeltaPminEditField_3.Value = 50;
            app.maxLabel_3 = uilabel(app.ApproximatevaluesPanel);
            app.maxLabel_3.Enable = 'off';
            app.maxLabel_3.Text = 'max';
            app.OptimDeltaPmaxEditField_3 = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPmaxEditField_3.Enable = 'off';
            app.OptimDeltaPmaxEditField_3.Value = 150;
            app.RunButton_2 = uibutton(app.OptimizationTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.Button_2 = uibutton(app.OptimizationTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button_2.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HARF_drawing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure)
        end
    end
end